classdef stereo_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        civ_input             matlab.ui.Figure
        MenuOpen              matlab.ui.container.Menu
        MenuBrowse            matlab.ui.container.Menu
        MenuFile_1            matlab.ui.container.Menu
        MenuFile_2            matlab.ui.container.Menu
        MenuFile_3            matlab.ui.container.Menu
        MenuFile_4            matlab.ui.container.Menu
        MenuFile_5            matlab.ui.container.Menu
        MenuHelpHead          matlab.ui.container.Menu
        MenuHelp              matlab.ui.container.Menu
        num_BkgndPeriod_B     matlab.ui.control.EditField
        Background_B          matlab.ui.control.EditField
        Mask_B                matlab.ui.control.EditField
        CheckPatch1           matlab.ui.control.CheckBox
        CheckCiv2             matlab.ui.control.CheckBox
        CheckCiv1             matlab.ui.control.CheckBox
        CheckPatch3           matlab.ui.control.CheckBox
        CheckFix3             matlab.ui.control.CheckBox
        CheckPatch2           matlab.ui.control.CheckBox
        CheckFix1             matlab.ui.control.CheckBox
        num_Maxtanh           matlab.ui.control.EditField
        CheckRescale          matlab.ui.control.CheckBox
        num_BkgndPeriod       matlab.ui.control.EditField
        Background            matlab.ui.control.EditField
        CheckBackground       matlab.ui.control.CheckBox
        num_NbSlice           matlab.ui.control.EditField
        num_MaxIma            matlab.ui.control.EditField
        num_MinIma            matlab.ui.control.EditField
        CheckThreshold        matlab.ui.control.CheckBox
        Mask_A                matlab.ui.control.EditField
        CheckMask             matlab.ui.control.CheckBox
        title_Threshold       matlab.ui.control.Label
        text118               matlab.ui.control.Label
        CheckCiv3             matlab.ui.control.CheckBox
        text117               matlab.ui.control.Label
        num_resolution        matlab.ui.control.EditField
        CheckTest             matlab.ui.control.CheckBox
        SetDefaultParam       matlab.ui.control.Button
        ImportParam           matlab.ui.control.Button
        ConfigSource          matlab.ui.control.EditField
        text93                matlab.ui.control.Label
        Fix3                  matlab.ui.container.Panel
        num_MinCorr_2         matlab.ui.control.EditField
        text110               matlab.ui.control.Label
        field_ref2_1          matlab.ui.control.DropDown
        text111               matlab.ui.control.Label
        num_MinVel_2          matlab.ui.control.EditField
        num_MaxVel_2          matlab.ui.control.EditField
        ref_fix2_1            matlab.ui.control.EditField
        get_ref_fix2_1        matlab.ui.control.CheckBox
        text109               matlab.ui.control.Label
        Patch3                matlab.ui.container.Panel
        text115               matlab.ui.control.Label
        num_FieldSmooth_2     matlab.ui.control.EditField
        text116               matlab.ui.control.Label
        num_SubDomainSize_2   matlab.ui.control.EditField
        num_MaxDiff_2         matlab.ui.control.EditField
        text112               matlab.ui.control.Label
        Civ3                  matlab.ui.container.Panel
        Grid_3                matlab.ui.control.EditField
        CheckGrid_3           matlab.ui.control.CheckBox
        text108               matlab.ui.control.Label
        num_SearchBoxSize_2   matlab.ui.control.EditField
        num_SearchRange_1_2   matlab.ui.control.EditField
        num_Dy_2              matlab.ui.control.EditField
        num_Dx_2              matlab.ui.control.EditField
        text107               matlab.ui.control.Label
        text106               matlab.ui.control.Label
        text105               matlab.ui.control.Label
        CheckDeformation_1    matlab.ui.control.CheckBox
        text104               matlab.ui.control.Label
        num_CorrSmooth_2      matlab.ui.control.EditField
        num_CorrBoxSize_2_2   matlab.ui.control.EditField
        num_CorrBoxSize_1_2   matlab.ui.control.EditField
        text103               matlab.ui.control.Label
        OK                    matlab.ui.control.Button
        CheckFix2             matlab.ui.control.CheckBox
        Civ1                  matlab.ui.container.Panel
        TestCiv1              matlab.ui.control.StateButton
        rho_title             matlab.ui.control.Label
        shift_title           matlab.ui.control.Label
        is_title              matlab.ui.control.Label
        ib_title              matlab.ui.control.Label
        parameters            matlab.ui.control.Label
        Grid                  matlab.ui.control.EditField
        CheckGrid             matlab.ui.control.CheckBox
        num_Dy_1              matlab.ui.control.EditField
        num_Dx_1              matlab.ui.control.EditField
        num_CorrSmooth_1      matlab.ui.control.EditField
        num_SearchBoxShift_2  matlab.ui.control.EditField
        num_SearchBoxShift_1  matlab.ui.control.EditField
        num_SearchRange_2_1   matlab.ui.control.EditField
        num_SearchRange_1_1   matlab.ui.control.EditField
        num_CorrBoxSize_2_1   matlab.ui.control.EditField
        num_CorrBoxSize_1_1   matlab.ui.control.EditField
        title_Dy_1            matlab.ui.control.Label
        title_Dx_1            matlab.ui.control.Label
        Fix1                  matlab.ui.container.Panel
        field_ref1            matlab.ui.control.DropDown
        title_MaxVel_1        matlab.ui.control.Label
        num_MinCorr_1         matlab.ui.control.EditField
        thresh_vecC_title     matlab.ui.control.Label
        num_MinVel_1          matlab.ui.control.EditField
        num_MaxVel_1          matlab.ui.control.EditField
        ref_fix1              matlab.ui.control.EditField
        get_ref_fix1          matlab.ui.control.CheckBox
        TitleMinVel           matlab.ui.control.Label
        Patch1                matlab.ui.container.Panel
        title_MaxDiff_1       matlab.ui.control.Label
        text75                matlab.ui.control.Label
        text72                matlab.ui.control.Label
        num_SubDomainSize_1   matlab.ui.control.EditField
        num_MaxDiff_1         matlab.ui.control.EditField
        num_FieldSmooth_1     matlab.ui.control.EditField
        Civ2                  matlab.ui.container.Panel
        Grid_2                matlab.ui.control.EditField
        CheckGrid_2           matlab.ui.control.CheckBox
        text94                matlab.ui.control.Label
        num_SearchRange_2     matlab.ui.control.EditField
        num_SearchRange_1     matlab.ui.control.EditField
        num_Dy                matlab.ui.control.EditField
        num_Dx                matlab.ui.control.EditField
        title_Dy              matlab.ui.control.Label
        title_Dx              matlab.ui.control.Label
        rho_civ2_title        matlab.ui.control.Label
        CheckDeformation      matlab.ui.control.CheckBox
        ibx_civ2_text         matlab.ui.control.Label
        num_CorrSmooth        matlab.ui.control.EditField
        num_CorrBoxSize_2     matlab.ui.control.EditField
        num_CorrBoxSize_1     matlab.ui.control.EditField
        parameters_text       matlab.ui.control.Label
        Fix2                  matlab.ui.container.Panel
        field_ref2            matlab.ui.control.DropDown
        title_MaxVel          matlab.ui.control.Label
        num_MinCorr           matlab.ui.control.EditField
        thresh_vec2C_text     matlab.ui.control.Label
        num_MinVel            matlab.ui.control.EditField
        num_MaxVel            matlab.ui.control.EditField
        ref_fix2              matlab.ui.control.EditField
        get_ref_fix2          matlab.ui.control.CheckBox
        thresh_vel2_text      matlab.ui.control.Label
        Patch2                matlab.ui.container.Panel
        title_MaxDiff         matlab.ui.control.Label
        FieldSmooth_txt       matlab.ui.control.Label
        num_SubDomainSize     matlab.ui.control.EditField
        num_MaxDiff           matlab.ui.control.EditField
        num_FieldSmooth       matlab.ui.control.EditField
        subdomain_text2       matlab.ui.control.Label
    end

    
    methods (Access = private)
        function CheckStereo_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- TO ABANDON Executes on button press in test_stereo1.
            
            %------------------------------------------------------------------------
            hparent=get(hObject,'parent');
            parent_tag=get(hparent,'Tag');
            hchildren=get(hparent,'children');
            handle_txtbox=findobj(hchildren,'tag','txt_Mask');
            if isequal(get(hObject,'Value'),0)
                set(handles.num_SubDomainSize,'Visible','on')
                set(handles.num_FieldSmooth,'Visible','on')
            else
                set(handles.num_SubDomainSize,'Visible','off')
                set(handles.num_FieldSmooth,'Visible','off')
            end
        end
        
        function Civ1_ImageB_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on carriage return on the subdir checkciv1 edit window
            
            %------------------------------------------------------------------------
            SubDir=get(handles.Civ1_ImageB,'String');
            menu_str=get(handles.ListSubdirCiv1,'String');% read the list of subdirectories for update
            ichoice=find(strcmp(SubDir,menu_str),1);
            if isempty(ichoice)
                ilist=numel(menu_str); %select 'new...' in the menu
            else
                ilist=ichoice;
            end
            set(handles.ListSubdirCiv1,'Value',ilist)% select the selected subdir in the menu
            if get(handles.CheckCiv1,'Value')% if Civ1 is performed
                set(handles.Civ2_ImageA,'String',SubDir);% set by default civ2 directory the same as civ1
            %     set(handles.ListSubdirCiv2,'Value',ilist)
            else % if Civ1 data already exist
                errormsg=find_netcpair_civ(app, handles,1); %update the list of available index pairs in the new directory
                if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                end
            end
        end
        
        function Civ2_ImageA_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on carriage return on the SubDir checkciv1 edit window
            
            %------------------------------------------------------------------------
            SubDir=get(handles.Civ1_ImageB,'String');
            menu_str=get(handles.ListSubdirCiv2,'String');% read the list of subdirectories for update
            ichoice=find(strcmp(SubDir,menu_str),1);
            if isempty(ichoice)
                ilist=numel(menu_str); %select 'new...' in the menu
            else
                ilist=ichoice;
            end
            set(handles.ListSubdirCiv2,'Value',ilist)% select the selected subdir in the menu
            %update the list of available pairs from netcdf files in the new directory
            if ~get(handles.CheckCiv2,'Value') && ~get(handles.CheckCiv1,'Value') && ~get(handles.CheckFix1,'Value') && ~get(handles.CheckPatch1,'Value')
                errormsg=find_netcpair_civ(app, handles,2);
                    if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                end
            end
        end
        
        function ListCompareMode_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on button press in ListCompareMode.
            
            %------------------------------------------------------------------------
            ListCompareMode=get(handles.ListCompareMode,'String');
            option=ListCompareMode{get(handles.ListCompareMode,'Value')};
            hseries=findobj(allchild(0),'Tag','series');
            SeriesData=get(hseries,'UserData');
            check_nc=strcmp(SeriesData.FileType{1},'.nc');
            ImageType=SeriesData.FileType(2:end);
            if check_nc
                ImageType=SeriesData.FileType(2:end);
            else
                ImageType=SeriesData.FileType;
            end
            hhseries=guidata(hseries);
            InputTable=get(hhseries.InputTable,'Data');
            OriginIndex='off';
            PairIndices='off';
            DoubleInputSeries='off';
            switch option
                case 'PIV'
                    PairIndices='on';% needs to define index pairs for PIV
            
                case 'PIV volume'
                    PairIndices='on';% needs to define index pairs for PIV
                    set(handles.ListPairMode,'Value',1)
                    set(handles.ListPairMode,'String',{'series(Di)'})
                    ListPairMode_Callback(app, hObject, eventdata, handles)
                case 'displacement'
                    OriginIndex='on';%define a frame origin for displacement
            %     case 'shift'
            %         if numel(ImageType)==1
            %             fileinput=uigetfile_uvmat('pick a second file series for synchronous shift',InputTable{check_nc+1});
            %             if ~isempty(fileinput)
            %                 series( 'display_file_name',hhseries,fileinput,'append')
            %             end
            %
            %
            %         end
            end
            set(handles.num_OriginIndex,'Visible',OriginIndex)
            set(handles.OriginIndex_title,'Visible',OriginIndex)
            set(handles.PairIndices,'Visible',PairIndices)
            ListPairMode_Callback(app, hObject, eventdata, handles)
        end
        
        function ListPairCiv1_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on selection change in ListPairCiv1.
            
            %------------------------------------------------------------------------
            %reproduce by default the chosen pair in the checkciv2 menu
            list_pair=get(handles.ListPairCiv1,'String');%get the menu of image pairs
            PairString=list_pair{get(handles.ListPairCiv1,'Value')};
            
            [ind1,ind2]=...
                find_pair_indices(app, PairString);
            hseries=findobj(allchild(0),'Tag','series');
            hhseries=guidata(hseries);
            set(hhseries.num_first_j,'String',num2str(ind1));
            set(hhseries.num_last_j,'String',num2str(ind2));
            set(hhseries.num_incr_j,'String',num2str(ind2-ind1));
            set(handles.ListPairCiv2,'Value',get(handles.ListPairCiv1,'Value'))%civ2 selection the same as civ& by default
        end
        
        function ListPairMode_Callback(app, hObject, eventdata, handles)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Callbacks in the uipanel Pair Indices
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------------------------------------------
            % --- Executes on button press in ListPairMode.
            
            %------------------------------------------------------------------------
            compare_list=get(handles.ListCompareMode,'String');
            val=get(handles.ListCompareMode,'Value');
            compare=compare_list{val};
            if strcmp(compare,'displacement')||strcmp(compare,'shift')
                mode='displacement';
            else
                mode_list=get(handles.ListPairMode,'String');
                if ischar(mode_list)
                    mode_list={mode_list};
                end
                mode_value=get(handles.ListPairMode,'Value');
                mode=mode_list{mode_value};
            end
            % displ_num=[];%default
            ref_i=str2double(get(handles.ref_i,'String'));
            % last_i=str2num(get(handles.last_i,'String'));
            CivInputData=get(handles.civ_input,'UserData');
            TimeUnit=get(handles.TimeUnit,'String');
            checkframe=strcmp(TimeUnit,'frame');
            time=CivInputData.Time;
            siztime=size(CivInputData.Time);
            nbfield=siztime(2)-1;
            nbfield2=siztime(1)-1;
            indchosen=1;  %%first pair selected by default
            %displ_num used to define the indices of the stereo_input pairs
            % in mode 'pair j1-j2', j1 and j2 are the file indices, else the indices
            % are relative to the reference indices ref_i and ref_j respectively.
            if isequal(mode,'pair j1-j2')%| isequal(mode,'st_pair j1-j2')
                dt=1;
                displ='';
                index=0;
                numlist_a=[];
                numlist_B=[];
                %get all the time intervals in bursts
                displ_dt=1;%default
                nbfield2=min(nbfield2,10);%limitate the number of pairs to 10x10
                for numod_a=1:nbfield2-1 %nbfield2 always >=2 for 'pair j1-j2' mode
                    for numod_b=(numod_a+1):nbfield2
                        index=index+1;
                        numlist_a(index)=numod_a;
                        numlist_b(index)=numod_b;
                        if size(time,2)>1 && ~checkframe
                            dt(numod_a,numod_b)=CivInputData.Time(numod_b+1,ref_i+1)-CivInputData.Time(numod_a+1,ref_i+1);%first time interval dt
                            displ_dt(index)=dt(numod_a,numod_b);
                        else
                            displ_dt(index)=1;
                        end
                    end
                end
                [dtsort,indsort]=sort(displ_dt);
                if ~isempty(numlist_a)
                    displ_num(1,:)=numlist_a(indsort);
                    displ_num(2,:)=numlist_b(indsort);
                end
                displ_num(3,:)=0;
                displ_num(4,:)=0;
                enable_j(app, handles, 'off')
            elseif isequal(mode,'series(Dj)') %| isequal(mode,'st_series(Dj)')
                index=1:200;
                displ_num(1,index)=-floor(index/2);
                displ_num(2,index)=ceil(index/2);
                displ_num(3:4,index)=zeros(2,200);
                enable_j(app, handles, 'on')
            elseif isequal(mode,'series(Di)') %| isequal(mode,'st_series(Di)')
            %     index=1:200;
            %     displ_num(1:2,index)=zeros(2,200);
            %     displ_num(3,index)=-floor(index/2);
            %     displ_num(4,index)=ceil(index/2);
                enable_i(app, handles, 'on')
                if nbfield2 > 1
                    enable_j(app, handles, 'on')
                else
                    enable_j(app, handles, 'off')
                end
            elseif isequal(mode,'displacement')%the pairs have the same indices
                displ_num(1,1)=0;
                displ_num(2,1)=0;
                displ_num(3,1)=0;
                displ_num(4,1)=0;
                if nbfield > 1 || nbfield==0
                    enable_i(app, handles, 'on')
                else
                    enable_j(app, handles, 'off')
                end
                if nbfield2 > 1
                    enable_j(app, handles, 'on')
                else
                    enable_j(app, handles, 'off')
                end
            end
            %set(handles.ListPairCiv1,'UserData',displ_num);
            errormsg=find_netcpair_civ(app,  handles,1);
                if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                end
        end
        
        function MinIndex_i_Callback(app, hObject, eventdata, handles)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Callbacks in the uipanel Reference Indices
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            first_i=str2double(get(handles.MinIndex_i,'String'));
            set(handles.ref_i,'String', num2str(first_i))% reference index for pair dt = first index
            ref_i_Callback(app, hObject, eventdata, handles)%refresh dispaly of dt for pairs (in case of non constant dt)
        end
        
        function SearchRange_Callback(app, hObject, eventdata, handles)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Callbacks in the uipanel Civ1
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------------------------------------------
            % --- Executes on button press in SearchRange: determine the search range num_SearchRange_1,num_SearchRange_2
            
            %------------------------------------------------------------------------
            %determine pair numbers
            if strcmp(get(handles.num_UMin,'Visible'),'off')
                set(handles.u_title,'Visible','on')
                set(handles.v_title,'Visible','on')
                set(handles.num_UMin,'Visible','on')
                set(handles.num_UMax,'Visible','on')
                set(handles.num_VMin,'Visible','on')
                set(handles.num_VMax,'Visible','on')
                %set(handles.CoordUnit,'Visible','on')
                %set(handles.TimeUnit,'Visible','on')
                %set(handles.slash_title,'Visible','on')
                set(handles.min_title,'Visible','on')
                set(handles.max_title,'Visible','on')
                set(handles.unit_title,'Visible','on')
            else
                get_search_range(app, hObject, eventdata, handles)
            end
        end
        
        function civ_input_CloseRequestFcn(app, hObject, eventdata, handles)
            % --- Executes when user attempts to close get_field.
            
            if isequal(get(handles.get_field, 'waitstatus'), 'waiting')
                % The GUI is still in UIWAIT, us UIRESUME
                uiresume(handles.civ_input);
            else
                % The GUI is no longer waiting, just close it
                delete(handles.civ_input);
            end
        end
        
        function closeview_field(app, gcbo, eventdata)
            %------------------------------------------------------------------------
            %----function introduced for the correlation window figure, activated by deleting this window
            
            %------------------------------------------------------------------------
            hview_field=findobj(allchild(0),'tag','view_field');% look for view_field
            if ~isempty(hview_field)
                delete(hview_field)
            end
        end
        
        function enable_i(app, handles, state)
            set(handles.itext,'Visible',state)
            % set(handles.MinIndex_i,'Visible',state)
            % set(handles.last_i,'Visible',state)
            % set(handles.incr_i,'Visible',state)
            set(handles.MaxIndex_i,'Visible',state)
            set(handles.ref_i,'Visible',state)
        end
        
        function enable_j(app, handles, state)
            set(handles.jtext,'Visible',state)
            % set(handles.MinIndex_j,'Visible',state)
            % set(handles.last_j,'Visible',state)
            % set(handles.incr_j,'Visible',state)
            set(handles.MinIndex_j,'Visible',state)
            set(handles.MaxIndex_j,'Visible',state)
            set(handles.ref_j,'Visible',state)
        end
        
        function fill_civ_input(app, Data, handles)
            %------------------------------------------------------------------------
            % --- fill stereo_input with the parameters retrieved from an input Civ file
            %------------------------------------------------------------------------
            
            
            %% Civ param
            % lists of parameters to enter
            ListParamNum={'CorrBoxSize','SearchBoxSize','SearchBoxShift','Dx','Dy','Dz','MinIma','MaxIma'};% list of numerical values (to transform in strings)
            ListParamValue={'CorrSmooth','CheckGrid','CheckMask','CheckThreshold'};
            ListParamString={'Grid','Mask'};
            % CorrSmooth ??
            option={'Civ1','Civ2'};
            for ichoice=1:2
                if isfield(Data,[option{ichoice} '_CorrBoxSize'])
                    fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
                end
            end
            
            %% Fix param
            option={'Fix1','Fix2'};
            for ichoice=1:2
                if isfield(Data,[option{ichoice} '_CheckFmin2'])
                    ListParamNum={'MinVel','MaxVel','MinCorr'};% list of numerical values (to transform in strings)
                    ListParamValue={'CheckFmin2','CheckF3','CheckF4'};
                    ListParamString={'ref_fix_1'};
                    fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
                end
            end
            
            %% Patch param
            option={'Patch1','Patch2'};
            for ichoice=1:2
                if isfield(Data,[option{ichoice} '_FieldSmooth'])
                    ListParamNum={'FieldSmooth','MaxDiff','SubDomainSize'};% list of numerical values (to transform in strings)
                    ListParamValue={};
                    ListParamString={};
                    fill_panel(app, Data,handles,option{ichoice},ListParamNum,ListParamValue,ListParamString)
                end
            end
        end
        
        function fill_panel(app, Data, handles, panel, ListParamNum, ListParamValue, ListParamString)
            %------------------------------------------------------------------------
            % --- fill a panel of stereo_input with the parameters retrieved from an input Civ file
            %------------------------------------------------------------------------
            
            children=get(handles.(panel),'children');%handles of the children of the input GUI with handle 'GUI_handle'
            handles_panel=[];
            for ichild=1:numel(children)
                if ~isempty(get(children(ichild),'tag'))
                    handles_panel.(get(children(ichild),'tag'))=children(ichild);
                end
            end
            for ilist=1:numel(ListParamNum)
                ParamName=ListParamNum{ilist};
                CivParamName=[panel '_' ParamName];
                if isfield(Data,CivParamName)
                    for icoord=1:numel(Data.(CivParamName))
                        if numel(Data.(CivParamName))>1
                            Tag=['num_' ParamName '_' num2str(icoord)];
                        else
                            Tag=['num_' ParamName];
                        end
                        if isfield(handles_panel,Tag)
                            set(handles_panel.(Tag),'String',num2str(Data.(CivParamName)(icoord)))
                            set(handles_panel.(Tag),'Visible','on')
                        end
                    end
                end
            end
            for ilist=1:numel(ListParamValue)
                ParamName=ListParamValue{ilist};
                CivParamName=[panel '_' ParamName];
                if strcmp(ParamName,'CorrSmooth')
                    ParamName=['num_' ParamName];
                end
                if isfield(Data,CivParamName)
                    if isfield(handles_panel,ParamName)
                        set(handles_panel.(ParamName),'Value',Data.(CivParamName))
                    end
                end
            end
            for ilist=1:numel(ListParamString)
                ParamName=ListParamString{ilist};
                CivParamName=[panel '_' ParamName];
                if isfield(Data,CivParamName)
                    if isfield(handles_panel,ParamName)
                        set(handles_panel.(ParamName),'String',Data.(CivParamName))
                    end
                end
            end
        end
        
        function errormsg = find_netcpair_civ(app, handles, index)
            % %------------------------------------------------------------------------
            % function ref_j_Callback(hObject, eventdata, handles)
            % %------------------------------------------------------------------------
            % mode_list=get(handles.ListPairMode,'String');
            % mode_value=get(handles.ListPairMode,'Value');
            % mode=mode_list{mode_value};
            % errormsg='';
            % if isequal(get(handles.CheckCiv1,'Value'),0)|| isequal(mode,'series(Dj)')
            %     errormsg=find_netcpair_civ(handles,1);% update the menu of pairs depending on the available netcdf files
            % end
            % if isequal(mode,'series(Dj)') || ...
            %         (get(handles.CheckCiv2,'Value')==0 && get(handles.CheckCiv1,'Value')==0 && get(handles.CheckFix1,'Value')==0 && get(handles.CheckPatch1,'Value')==0)
            %     errormsg=find_netcpair_civ(handles,2);
            % end
            % if ~isempty(errormsg)
            %     msgbox_uvmat('ERROR',errormsg)
            % end
            %
            % function ref_j_KeyPressFcn(hObject, eventdata, handles)
            % set(handles.ref_j,'BackgroundColor',[1 0 1])
            %------------------------------------------------------------------------
            % determine the menu for checkciv1 pairs depending on existing netcdf file at the middle of
            % the field series set by MinIndex_i, incr, last_i
            % index=1: look for pairs for civ1
            % index=2: look for pairs for civ2
            
            %------------------------------------------------------------------------
            set(gcf,'Pointer','watch')% set the mouse pointer to 'watch' (clock)
            
            %% initialisation
            errormsg='';
            CivInputData=get(handles.civ_input,'UserData');
            %browse=get(handles.RootPath,'UserData');
            compare_list=get(handles.ListCompareMode,'String');
            val=get(handles.ListCompareMode,'Value');
            compare=compare_list{val};
            if strcmp(compare,'displacement')||strcmp(compare,'shift')
                mode='displacement';
            else
                mode_list=get(handles.ListPairMode,'String');
                mode_value=get(handles.ListPairMode,'Value');
                if isempty(mode_list)
                    return
                end
                mode=mode_list{mode_value};
            end
            nom_type_ima=CivInputData.NomTypeIma;
            
            %% determine nom_type_nc, nomenclature type of the .nc files:
            %[nom_type_nc]=nomtype2pair(nom_type_ima,mode);
            
            %% reads .nc subdirectoy and image numbers from the interface
            %SubDirImages=get(handles.Civ1_ImageA,'String');
            %TODO: determine
            %subdir_civ1=[SubDirImages get(handles.Civ1_ImageB,'String')];%subdirectory subdir_civ1 for the netcdf data
            %subdir_civ2=[SubDirImages get(handles.Civ2_ImageA,'String')];%subdirectory subdir_civ2 for the netcdf data
            ref_i=str2double(get(handles.ref_i,'String'));
            ref_j=[];
            if isequal(mode,'pair j1-j2')%|isequal(mode,'st_pair j1-j2')
                ref_j=0;
            elseif strcmp(get(handles.ref_j,'Visible'),'on')
                ref_j=str2double(get(handles.ref_j,'String'));
            end
            if isempty(ref_j)
                ref_j=1;
            end
            CivInputData=get(handles.civ_input,'UserData');
            TimeUnit=get(handles.TimeUnit,'String');
            Time=CivInputData.Time;
            checkframe=strcmp(TimeUnit,'frame');
            
            %% case with no Civ1 operation, netcdf files need to exist for reading
            displ_pair={''};
            nbpair=200;%default
            select=ones(size(1:nbpair));%flag for displayed pairs =1 for display
            nbpair=200; %default
            
            %% determine the menu display in .ListPairCiv1
            switch mode
                case 'series(Di)'
                    for ipair=1:nbpair
                        if select(ipair)
                            displ_pair{ipair}=['Di= ' num2str(-floor(ipair/2)) '|' num2str(ceil(ipair/2))];
                            if ~checkframe
                                if size(Time,1)>=ref_i+1+ceil(ipair/2) && size(Time,2)>=ref_j+1&& ref_i-floor(ipair/2)>=0 && ref_j>=0
                                    dt=Time(ref_i+1+ceil(ipair/2),ref_j+1)-Time(ref_i+1-floor(ipair/2),ref_j+1);%Time interval dtref_j+1
                                    displ_pair{ipair}=[displ_pair{ipair} ' :dt= ' num2str(dt*1000)];
                                end
                            else
                                dt=ipair/1000;
                                displ_pair{ipair}=[displ_pair{ipair} ' :dt= ' num2str(ipair)];
                            end
                        else
                            displ_pair{ipair}='...'; %pair not displayed in the menu
                        end
                    end
                case 'series(Dj)'
                    for ipair=1:nbpair
                        if select(ipair)
                            displ_pair{ipair}=['Dj= ' num2str(-floor(ipair/2)) '|' num2str(ceil(ipair/2))];
                            if ~checkframe
                                if size(Time,2)>=ref_j+1+ceil(ipair/2) && size(Time,1)>=ref_i+1 && ref_j-floor(ipair/2)>=0 && ref_i>=0
                                    dt=Time(ref_i+1,ref_j+1+ceil(ipair/2))-Time(ref_i+1,ref_j+1-floor(ipair/2));%Time interval dtref_j+1
                                    displ_pair{ipair}=[displ_pair{ipair} ' :dt= ' num2str(dt*1000)];
                                end
                            else
                                dt=ipair/1000;
                                displ_pair{ipair}=[displ_pair{ipair} ' :dt= ' num2str(dt*1000)];
                            end
                        else
                            displ_pair{ipair}='...'; %pair not displayed in the menu
                        end
                    end
                case 'pair j1-j2'%case of pairs
            %         MinIndex_j=CivInputData.MinIndex_j;
            %         MaxIndex_j=min(CivInputData.MaxIndex_j,10);%limitate the number of pairs to 10x10
                    MinIndex_j=str2num(get(handles.MinIndex_j,'String'));
                    MaxIndex_j=str2num(get(handles.MaxIndex_j,'String'));
                    index_pair=0;
                    %get all the Time intervals in bursts
                    for numod_a=MinIndex_j:MaxIndex_j-1 %nbfield2 always >=2 for 'pair j1-j2' mode
                        for numod_b=(numod_a+1):MaxIndex_j
                            index_pair=index_pair+1;
                            displ_pair{index_pair}=['j= ' num2stra(app, numod_a,nom_type_ima) '-' num2stra(app, numod_b,nom_type_ima)];
                            dt(index_pair)=numod_b-numod_a;%default dt
                            if size(Time,1)>ref_i && size(Time,2)>numod_b  % && ~checkframe
                                dt(index_pair)=Time(ref_i+1,numod_b+1)-Time(ref_i+1,numod_a+1);% Time interval dt
                                displ_pair{index_pair}=[displ_pair{index_pair} ' :dt= ' num2str(dt(index_pair)*1000)];
                            end
                        end
            
                    end
                    [tild,indsort]=sort(dt);
                    displ_pair=displ_pair(indsort);
                case 'displacement'
                    displ_pair={'Di=Dj=0'};
            end
            if index==1
                set(handles.ListPairCiv1,'String',displ_pair');
            end
            
            %% determine the default selection in the pair menu for Civ1
            ichoice=find(select,1);% index of first selected pair
            if (isempty(ichoice) || ichoice < 1); ichoice=1; end;
            initial=get(handles.ListPairCiv1,'Value');%initial choice of pair
            if initial>nbpair || (numel(select)>=initial && ~isequal(select(initial),1))
                set(handles.ListPairCiv1,'Value',ichoice);% first valid pair proposed by default in the menu
            end
            
            %% determine the default selection in the pair menu for Civ2
            if strcmp(get(handles.ListPairCiv2,'Visible'),'on')
                initial=get(handles.ListPairCiv2,'Value');
                if initial>length(displ_pair')%|~isequal(select(initial),1)
                    if ichoice <= length(displ_pair')
                        set(handles.ListPairCiv2,'Value',ichoice);% same pair proposed by default for civ2
                    else
                        set(handles.ListPairCiv2,'Value',1);% same pair proposed by default for civ2
                    end
                end
            else
                set(handles.ListPairCiv2,'Value',get(handles.ListPairCiv1,'Value'))% initiate the choice of Civ2 as a reproduction of if civ1
            end
            set(handles.ListPairCiv2,'String',displ_pair');
            set(gcf,'Pointer','arrow')% Indicate that the process is finished
        end
        
        function [ind1, ind2, mode] = find_pair_indices(app, str_civ, i_series, j_series, MinIndex_i, MaxIndex_i, MinIndex_j, MaxIndex_j)
            %------------------------------------------------------------------------
            % --- determine the list of index pairs of processing file
            
            %------------------------------------------------------------------------
            ind1='';
            ind2='';
            r=regexp(str_civ,'^\D(?<ind>[i|j])=( -| )(?<num1>\d+)\|(?<num2>\d+)','names');
            if ~isempty(r)
                mode=['D' r.ind];
                ind1=stra2num(r.num1);
                ind2=stra2num(r.num2);
            else
                mode='burst';
                r=regexp(str_civ,'^j= (?<num1>[a-z])-(?<num2>[a-z])','names');
                if ~isempty(r)
                    NomTypeNc='_1ab';
                else
                    r=regexp(str_civ,'^j= (?<num1>[A-Z])-(?<num2>[A-Z])','names');
                    if ~isempty(r)
                        NomTypeNc='_1AB';
                    else
                        r=regexp(str_civ,'^j= (?<num1>\d+)-(?<num2>\d+)','names');
                        if ~isempty(r)
                            NomTypeNc='_1_1-2';
                        end
                    end
                end
                if isempty(r)
                    display('wrong pair mode input option')
                else
                ind1=stra2num(r.num1);
                ind2=stra2num(r.num2);
                end
            end
        end
        
        function [nbslice, flag_grid] = get_grid(app, filebase, handles)
            %------------------------------------------------------------------------
            % --- function called to look for grid files
            
            %------------------------------------------------------------------------
            flag_grid=0;%default
            nbslice=1;
            [Path,Name]=fileparts(filebase);
            currentdir=pwd;
            cd(Path);%move in the dir of the root name filebase
            gridfiles=dir([Name '_*grid_*.grid']);%look for grid files
            cd(currentdir);%come back to the current working directory
            if ~isempty(gridfiles)
                flag_grid=1;
                gridname=gridfiles(1).name;% take the first grid file in the list
                [Path2,Name,ext]=fileparts(gridname);
                Namedouble=double(Name);
                val=(48>Namedouble)|(Namedouble>57);% select the non-numerical characters
                ind_grid=findstr('grid',Name);
                i=ind_grid-1;
                while val(i)==0 && i>0
                    i=i-1;
                end
                nbslice=str2double(Name(i+1:ind_grid-1));
                if ~isnan(nbslice) && Name(i)=='_'
                    flag_grid=1;
                else
                    msgbox_uvmat('ERROR',['bad grid file ' Name ext ' found in ' Path2])
                    return
                    nbslice=1;
                end
            end
        end
        
        function [nbslice, flag_mask] = get_mask(app,filebase)
            %------------------------------------------------------------------------
            % --- function called to look for mask files
            
            %------------------------------------------------------------------------
            %detect mask files, images with appropriate file base
            %[filebase '_' xx 'mask'], xx=nbslice
            %flag_mask=1 indicates detection
            
            flag_mask=0;%default
            nbslice=1;
            
            % subdir=get(handles.Civ1_ImageB,'String');
            [Path,Name]=fileparts(filebase);
            if ~isfolder(Path)
                msgbox_uvmat('ERROR','no path for input files')
                return
            end
            % currentdir=pwd;
            % cd(Path);%move in the dir of the root name filebase
            maskfiles=dir(fullfile(Path,[Name '_*mask_*.png']));%look for mask files
            % cd(currentdir);%come back to the current working directory
            if ~isempty(maskfiles)
                %     msgbox_uvmat('ERROR','no mask available, to create it use Tools/Make mask in the upper menu bar of uvmat')
                % else
                flag_mask=1;
                maskname=maskfiles(1).name;% take the first mask file in the list
                [Path2,Name,ext]=fileparts(maskname);
                Namedouble=double(Name);
                val=(48>Namedouble)|(Namedouble>57);% select the non-numerical characters
                ind_mask=findstr('mask',Name);
                i=ind_mask-1;
                while val(i)==0 && i>0
                    i=i-1;
                end
                nbslice=str2double(Name(i+1:ind_mask-1));
                if ~isnan(nbslice) && Name(i)=='_'
                    flag_mask=1;
                else
                    msgbox_uvmat('ERROR',['bad mask file ' Name ext ' found in ' Path2])
                    return
                    nbslice=1;
                end
            end
        end
        
        % function get_mask_civ2_Callback(app, hObject, eventdata, handles)
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     % Callbacks in the uipanel Civ2
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     %------------------------------------------------------------------------
        %     % --- Executes on button press in CheckMask: select box for mask option
        % 
        %     %------------------------------------------------------------------------
        %     maskval=get(handles.CheckMask,'Value');
        %     if isequal(maskval,0)
        %         set(handles.Mask,'String','')
        %     else
        %         mask_displ='no mask'; %default
        %         filebase=get(handles.RootPath,'String');
        %         [nbslice, flag_mask]=get_mask(app, filebase,handles);
        %         if isequal(flag_mask,1)
        %             mask_displ=[num2str(nbslice) 'mask'];
        %         elseif get(handles.ListCompareMode,'Value')>1 & ~isequal(mask_displ,'no mask')% look for the second mask series
        %             filebase_a=get(handles.RootFile_1,'String');
        %             [nbslice_a, flag_mask_a]=get_mask(app, filebase_a,handles);
        %             if isequal(flag_mask_a,0) || ~isequal(nbslice_a,nbslice)
        %                 mask_displ='no mask';
        %             end
        %         end
        %         if isequal(mask_displ,'no mask')
        %             [FileName, PathName, filterindex] = uigetfile( ...
        %                 {'*.png', ' (*.png)';
        %                 '*.png',  '.png files '; ...
        %                 '*.*', 'All Files (*.*)'}, ...
        %                 'Pick a mask file *.png',filebase);
        %             mask_displ=fullfile(PathName,FileName);
        %             if ~exist(mask_displ,'file')
        %                 mask_displ='no mask';
        %             end
        %         end
        %         if isequal(mask_displ,'no mask')
        %             set(handles.CheckMask,'Value',0)
        %             set(handles.CheckMask,'Value',0)
        %         else
        %             set(handles.CheckMask,'Value',1)
        %         end
        %         set(handles.Mask,'String',mask_displ)
        %     end
        % end
        
        function get_search_range(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % ---  determine the search range num_SearchRange_1,num_SearchRange_2 and shift
            
            %------------------------------------------------------------------------
            param_civ1=read_GUI(handles.Civ1);
            umin=param_civ1.UMin;
            umax=param_civ1.UMax;
            vmin=param_civ1.VMin;
            vmax=param_civ1.VMax;
            %switch min_title and max_title in case of error
            if umax<=umin
                umin_old=umin;
                umin=umax;
                umax=umin_old;
                set(handles.num_UMin,'String', num2str(umin))
                set(handles.num_UMax,'String', num2str(umax))
            end
            if vmax<=vmin
                vmin_old=vmin;
                vmin=vmax;
                vmax=vmin_old;
                set(handles.num_VMin,'String', num2str(vmin))
                set(handles.num_VMax,'String', num2str(vmax))
            end
            if ~(isempty(umin)||isempty(umax)||isempty(vmin)||isempty(vmax))
            %     list_pair=get(handles.ListPairCiv1,'String');%get the menu of image pairs
            %     index=get(handles.ListPairCiv1,'Value');
            %     pair_string=list_pair{index};
            %     time=get(handles.TimeSource,'UserData'); %get the set of times
            %     pxcm=get(handles.SearchRange,'UserData');
            %     mode_list=get(handles.ListPairMode,'String');
            %     mode_value=get(handles.ListPairMode,'Value');
            %     mode=mode_list{mode_value};
            %     if isequal (mode, 'series(Di)' )
            %         ref_i=str2double(get(handles.ref_i,'String'));
            %         num1=ref_i-floor(index/2);%  first image numbers
            %         num2=ref_i+ceil(index/2);
            %         num_a=1;
            %         num_b=1;
            %     elseif isequal (mode, 'series(Dj)')
            %         num1=1;
            %         num2=1;
            %         ref_j=str2double(get(handles.ref_j,'String'));
            %         num_a=ref_j-floor(index/2);%  first image numbers
            %         num_b=ref_j+ceil(index/2);
            %     elseif isequal(mode,'pair j1-j2') %case of bursts (png_old or png_2D)
            %         ref_i=str2double(get(handles.ref_i,'String'));
            %         num1=ref_i;
            %         num2=ref_i;
            %                 r=regexp(pair_string,'(?<mode>(Di=)|(Dj=)) -*(?<num1>\d+)\|(?<num2>\d+)','names');
            %         if isempty(r)
            %             r=regexp(pair_string,'(?<num1>\d+)(?<mode>-)(?<num2>\d+)','names');
            %         end
            %         num_a=str2num(r.num1);
            %         num_b=str2num(r.num2);
            %     end
            %     dt=time(num2+1,num_b+1)-time(num1+1,num_a+1);
            %     ibx=str2double(get(handles.num_CorrBoxSize_1,'String'));
            %     iby=str2double(get(handles.num_CorrBoxSize_2,'String'));
            %     umin=dt*pxcm*umin;
            %     umax=dt*pxcm*umax;
            %     vmin=dt*pxcm*vmin;
            %     vmax=dt*pxcm*vmax;
                shiftx=round((umin+umax)/2);
                shifty=round((vmin+vmax)/2);
                isx=(umax+2-shiftx)*2+param_civ1.CorrBoxSize(1);
                isx=2*ceil(isx/2)+1;
                isy=(vmax+2-shifty)*2+param_civ1.CorrBoxSize(2);
                isy=2*ceil(isy/2)+1;
                set(handles.num_SearchBoxShift_1,'String',num2str(shiftx));
                set(handles.num_SearchBoxShift_2,'String',num2str(shifty));
                set(handles.num_SearchRange_1,'String',num2str(isx));
                set(handles.num_SearchRange_2,'String',num2str(isy));
            end
        end
        
        function keyboard_callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            % --- Executes on key press with selection of a uicontrol
            %------------------------------------------------------------------------
            
            
            ListExclude={'CheckCiv1','CheckFix1','CheckPatch1','CheckCiv2','CheckFix2','CheckPatch2','ref_i'};
            if isempty(find(strcmp(get(gco,'Tag'),ListExclude),1))% if the selected uicontrol is not in the Exclude list
                set(handles.ConfigSource,'String','NEW')% indicate that the configuration is new
                set(handles.OK,'BackgroundColor',[1 0 1])%
                drawnow
            end
            
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            % if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
            %     set(hObject,'BackgroundColor','white');
            % end
        end
        
        function NomTypeNc = nomtype2pair(app, NomTypeIma, mode)
            %'nomtype2pair': creates nomencalture for index pairs knowing the image nomenclature
            %---------------------------------------------------------------------
            
            %---------------------------------------------------------------------
            % OUTPUT:
            % NomTypeNc
            %---------------------------------------------------------------------
            % INPUT:
            % 'NomTypeIma': string defining the kind of nomenclature used for images
            
            NomTypeNc=NomTypeIma;%default
            switch mode
                case 'pair j1-j2'
                if ~isempty(regexp(NomTypeIma,'a$'))
                    NomTypeNc=[NomTypeIma 'b'];
                elseif ~isempty(regexp(NomTypeIma,'A$'))
                    NomTypeNc=[NomTypeIma 'B'];
                else
                    r=regexp(NomTypeIma,'(?<num1>\d+)_(?<num2>\d+)$','names');
                    if ~isempty(r)
                        NomTypeNc='_1_1-2';
                    end
                end
                case 'series(Dj)'
            %         r=regexp(NomTypeIma,'(?<num1>\d+)_(?<num2>\d+)$','names');
            %         if ~isempty(r)
                        NomTypeNc='_1_1-2';
            %         end
               case 'series(Di)'
                    r=regexp(NomTypeIma,'(?<num1>\d+)_(?<num2>\d+)$','names');
                    if ~isempty(r)
                        NomTypeNc='_1-2_1';
                    else
                        NomTypeNc='_1-2';
                    end
            end
        end
        
        function str = num2stra(app, num, nom_type)
            %------------------------------------------------------------------------
            % --- transform numbers to letters
            
            %------------------------------------------------------------------------
            if isempty(nom_type)
                str='';
            elseif strcmp(nom_type(end),'a')
                str=char(96+num);
            elseif strcmp(nom_type(end),'A')
                str=char(96+num);
            elseif isempty(nom_type(2:end))%a single index
                str='';
            else
                str=num2str(num);
            end
        end
        
        function ref_fix1_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            set(handles.num_MinVel,'Value',1);
            set(handles.field_ref1,'Value',1)
            set(handles.field_ref1,'String',{' '})
            set(handles.ref_fix1,'UserData',[]);
            set(handles.ref_fix1,'String','');
            set(handles.thresh_vel1,'String','0');
        end
        
        function ref_fix2_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            set(handles.num_MinVel,'Value',1);
            set(handles.field_ref2,'Value',1)
            set(handles.field_ref2,'String',{' '})
            set(handles.ref_fix2,'UserData',[]);
            set(handles.ref_fix2,'String','');
            set(handles.num_MinVel,'String','0');
        end
        
        function ref_i_Callback(app, hObject, eventdata, handles)
            %------------------------------------------------------------------------
            
            %------------------------------------------------------------------------
            mode_list=get(handles.ListPairMode,'String');
            mode_value=get(handles.ListPairMode,'Value');
            mode=mode_list{mode_value};
            errormsg=find_netcpair_civ(app, handles,1);% update the menu of pairs depending on the available netcdf files
            if isequal(mode,'series(Di)') || ...% we do patch2 only
                    (get(handles.CheckCiv2,'Value')==0 && get(handles.CheckCiv1,'Value')==0 && get(handles.CheckFix1,'Value')==0 && get(handles.CheckPatch1,'Value')==0)
                errormsg=find_netcpair_civ(app,  handles,2);
            end
            if isempty(errormsg)
                set(handles.ref_i,'BackgroundColor',[1 1 1])
                set(handles.ref_j,'BackgroundColor',[1 1 1])
            else
                msgbox_uvmat('ERROR',errormsg)
            end
        end
        
        function ref_i_KeyPressFcn(app, hObject, eventdata, handles)
            set(hObject,'BackgroundColor',[1 0 1])
        end
        
        function update_CivOptions(app, handles, ind_input)
            % --- activated by any checkbox controling the selection of Civ1,Fix1,Patch1,Civ2,Fix2,Patch2
            
            %------------------------------------------------------------------------
            % if opening>0
            %     set(handles.CheckCiv2,'UserData',opening)% store the info on the current status of the civ processing
            % % end
            checkbox=zeros(1,9);
            checkbox(1)=get(handles.CheckCiv1,'Value');
            checkbox(2)=get(handles.CheckFix1,'Value');
            checkbox(3)=get(handles.CheckPatch1,'Value');
            checkbox(4)=get(handles.CheckCiv2,'Value');
            checkbox(5)=get(handles.CheckFix2,'Value');
            checkbox(6)=get(handles.CheckPatch2,'Value');
            checkbox(7)=get(handles.CheckCiv3,'Value');
            checkbox(8)=get(handles.CheckFix3,'Value');
            checkbox(9)=get(handles.CheckPatch3,'Value');
            
            % if max(checkbox(4:6))>0% case of civ2 pair choice needed
            %     set(handles.TitlePairCiv2,'Visible','on')
            %     set(handles.ListPairCiv2,'Visible','on')
            %     if ~opening
            %         errormsg=find_netcpair_civ(handles,2); % select the available netcdf files
            %         if ~isempty(errormsg)
            %             msgbox_uvmat('ERROR',errormsg)
            %         end
            %     end
            % else
            %     set(handles.ListPairCiv2,'Visible','off')
            % end
            %
            %
            %% set the visibility of the different panels
            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2','Civ3','Patch3','Patch3'};
            for ilist=1:min(ind_input,length(options))
            %     if checkbox(ilist)
                    set(handles.(options{ilist}),'Visible','on')
            %     else
            %         set(handles.(options{ilist}),'Visible','off')
            %     end
            end
        end
        
        function update_frame(app, CivStage)
            %------------------------------------------------------------------------
            options={'Civ1','Fix1','Patch1','Civ2','Fix2','Patch2','Civ3','Fix3','Patch3'};
            check_on=app.(['Check' options{CivStage}]).Value;
            
            if check_on % activate frames
                status=false;
                for index=1:CivStage
                    if app.(['Check' options{index}]).Value
                        status=true;
                    end
                    if status
                        app.(options{index}).Visible='on';
                        app.(['Check' options{index}]).Value=true;
                        % set(handles.(options{index}),'Visible','on')
                        % set(handles.(['Check' options{index}]),'Value',1)
                    end
                end
            else
                for index=CivStage:numel(options)
                     app.(options{index}).Visible='off';
                        app.(['Check' options{index}]).Value=0;
                    % set(handles.(options{index}),'Visible','off')
                    % set(handles.(['Check' options{index}]),'Value',0)
                end
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('stereo_input_App.mat');
            
            % Set component properties that require runtime configuration
            app.num_FieldSmooth.UserData = componentData.num_FieldSmooth.UserData;
            app.num_MaxDiff.UserData = componentData.num_MaxDiff.UserData;
            app.num_SubDomainSize.UserData = componentData.num_SubDomainSize.UserData;
            app.num_MinCorr.UserData = componentData.num_MinCorr.UserData;
            app.num_CorrBoxSize_1.UserData = componentData.num_CorrBoxSize_1.UserData;
            app.num_CorrBoxSize_2.UserData = componentData.num_CorrBoxSize_2.UserData;
            app.num_CorrSmooth.UserData = componentData.num_CorrSmooth.UserData;
            app.num_SearchRange_1.UserData = componentData.num_SearchRange_1.UserData;
            app.num_SearchRange_2.UserData = componentData.num_SearchRange_2.UserData;
            app.num_FieldSmooth_1.UserData = componentData.num_FieldSmooth_1.UserData;
            app.num_MaxDiff_1.UserData = componentData.num_MaxDiff_1.UserData;
            app.num_SubDomainSize_1.UserData = componentData.num_SubDomainSize_1.UserData;
            app.num_MinCorr_1.UserData = componentData.num_MinCorr_1.UserData;
            app.num_CorrBoxSize_1_1.UserData = componentData.num_CorrBoxSize_1_1.UserData;
            app.num_CorrBoxSize_2_1.UserData = componentData.num_CorrBoxSize_2_1.UserData;
            app.num_SearchRange_1_1.UserData = componentData.num_SearchRange_1_1.UserData;
            app.num_SearchRange_2_1.UserData = componentData.num_SearchRange_2_1.UserData;
            app.num_SearchBoxShift_1.UserData = componentData.num_SearchBoxShift_1.UserData;
            app.num_SearchBoxShift_2.UserData = componentData.num_SearchBoxShift_2.UserData;
            app.num_CorrSmooth_1.UserData = componentData.num_CorrSmooth_1.UserData;
            app.num_Dx_1.UserData = componentData.num_Dx_1.UserData;
            app.num_Dy_1.UserData = componentData.num_Dy_1.UserData;
            app.num_CorrBoxSize_1_2.UserData = componentData.num_CorrBoxSize_1_2.UserData;
            app.num_CorrBoxSize_2_2.UserData = componentData.num_CorrBoxSize_2_2.UserData;
            app.num_CorrSmooth_2.UserData = componentData.num_CorrSmooth_2.UserData;
            app.num_SearchRange_1_2.UserData = componentData.num_SearchRange_1_2.UserData;
            app.num_SearchBoxSize_2.UserData = componentData.num_SearchBoxSize_2.UserData;
            app.num_MaxDiff_2.UserData = componentData.num_MaxDiff_2.UserData;
            app.num_SubDomainSize_2.UserData = componentData.num_SubDomainSize_2.UserData;
            app.num_FieldSmooth_2.UserData = componentData.num_FieldSmooth_2.UserData;
            app.num_MinCorr_2.UserData = componentData.num_MinCorr_2.UserData;
            app.num_resolution.UserData = componentData.num_resolution.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function stereo_input_OpeningFcn(app, varargin)
            %------------------------------------------------------------------------
            % --- Executes just before stereo_input is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.civ_input, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            % This function has no output args, see OutputFcn.
            
            %% General settings
            if ~isempty(varargin)
            handles.output =  varargin{1};%Param;
            Param= varargin{1};
            end
            guidata(hObject, handles); % Update handles structure
             %data = guidata(hObject)
            set(hObject,'WindowButtonDownFcn',{'mouse_down'}) % allows mouse action with right button (zoom for uicontrol display)
            set(hObject,'WindowKeyPressFcn',{@app.keyboard_callback,handles})%set keyboard action function
            %set(hObject,'KeyPressFcn',{@KeyPressFcn,handles})%set keyboard action function
            %set(handles.ref_i,'KeyPressFcn',{@app.ref_i_KeyPressFcn,handles})%set keyboard action function
            %set(handles.ref_j,'KeyPressFcn',{@app.ref_i_KeyPressFcn,handles})%set keyboard action function
            %set(hObject,'WindowKeyPressFcn',{'keyboard_callback',handles})%set keyboard action function
            hseries=findobj(allchild(0),'Tag','series');% find the parent GUI 'series'
            hhseries=guidata(hseries); %handles of the elements in 'series'
            SeriesData=get(hseries,'UserData');% info stored in the GUI series
            
            
            % switch Param.Action.ActionName
            %     case 'stereo_civ'
            %         set(handles.ListCompareMode,'Visible','off')
            %         set(handles.PairIndices,'Visible','off')
            %     case 'civ_series'
            %         set(handles.ListCompareMode,'Visible','on')
            %         set(handles.PairIndices,'Visible','on')
            % set(handles.CheckPatch3,'parent',handles.civ_input)
            % set(handles.CheckFix3,'Position',[7 180 74 20])
            % set(handles.CheckPatch3,'Position',[7 120 74 20])
            FileType='image';%fdefault
            FileInfo=[];
            if isfield(SeriesData,'FileType')&&isfield(SeriesData,'FileInfo')
                FileType=SeriesData.FileType{1};%type of the first input file series
                FileInfo=SeriesData.FileInfo{1};% info on the first input file series
            else
                set(hhseries.REFRESH,'BackgroundColor',[1 0 1])% indicate that the file input in series needs to be refreshed
            end
            
            %% case of netcdf file as input, read the processing stage and look for corresponding images
            ind_opening=0;%default
            NomTypeNc='';
            %NomTypeImaA=NomTypeInput;
            iview_image=1;%line # for the input images
            switch FileType
                case {'image','multimage','video','mmreader','cine_phantom','netcdf'}
            %         NomTypeImaA=NomTypeInput;
            %         iview_image=1;%line # for the input images
                case 'civdata'
                    if ~strcmp(Param.Action.ActionName,'civ_series')
                        msgbox_uvmat('ERROR','bad input data file: open an image or a nc file from civ_series')
                        return
                    end
                    NomTypeNc=NomTypeInput;
                    ind_opening=FileInfo.CivStage;
                    if isempty(regexp(NomTypeInput,'[ab|AB|-]', 'once'))
                        set(handles.ListCompareMode,'Value',2) %mode displacement advised if the nomencalture does not involve index pairs
                    else
                        set(handles.ListCompareMode,'Value',1)
                    end
                    [Data,tild,tild,errormsg]=nc2struct(FileInfo.FileName,[]);
                    if ~isempty(errormsg)
                        msgbox_uvmat('ERROR',['error in netcdf input file: ' errormsg])
                        return
                    end
                    [PathCiv1_ImageA,Civ1_ImageA,FileExtA]=fileparts(Data.Civ1_ImageA);%look for the source image A
                    [PathCiv1_ImageB,Civ1_ImageB,FileExtA]=fileparts(Data.Civ1_ImageB);%look for the source image B
                    if isfield(Data,'Civ2_ImageA')
                        [PathCiv2_ImageA,Civ2_ImageA,FileExtA]=fileparts(Data.Civ2_ImageA);
                        [PathCiv2_ImageB,Civ2_ImageB,FileExtA]=fileparts(Data.Civ2_ImageB);
                    end
                    if size(Param.InputTable,1)==1
                        series('display_file_name',hhseries,Data.Civ1_ImageA,'append');%append the image series to the input list
                    end
                    [RootPath,SubDir,RootFile,i1,i2,j1,j2,FileExt,NomTypeImaA]=fileparts_uvmat(Data.Civ1_ImageA);
                    [RootPath,SubDir,RootFile,i1,i2,j1,j2,FileExt,NomTypeImaB]=fileparts_uvmat(Data.Civ1_ImageB);
                    iview_image=2;%line # for the input images
                case 'civxdata'% case of  civx data,
                    msgbox_uvmat('ERROR','old civX convention, use the GUI civ')
                    return
                otherwise
                    msgbox_uvmat('ERROR','civ_series needs images, scalar fields in netcdf format, or civ data as input')
                    return
            end
            
            %% reinitialise menus
            % set(handles.ListPairMode,'Value',1)
            % set(handles.ListPairMode,'String',{''})
            % set(handles.ListPairCiv1,'Value',1)
            % set(handles.ListPairCiv1,'String',{''})
            % set(handles.ListPairCiv2,'Value',1)
            % set(handles.ListPairCiv2,'String',{''})
            
            %% prepare the GUI with input parameters
            %
            % set(handles.ref_i,'String',num2str(Param.IndexRange.first_i))
            % if isfield(Param.IndexRange,'first_j')
            %     set(handles.ref_j,'String',num2str(Param.IndexRange.first_j))
            % end
            set(handles.ConfigSource,'String','\default')
            
            %%  set the menus of image pairs and default selection for stereo_input   %%%%%%%%%%%%%%%%%%%
            
            %% display the min and max indices for the whole file series
            % if isempty(Param.IndexRange.MaxIndex_i)|| isempty(Param.IndexRange.MinIndex_i)
            %     msgbox_uvmat('ERROR','REFRESH the input files in the GUI series')
            %      return
            % end
            % MaxIndex_i=Param.IndexRange.MaxIndex_i(iview_image);
            % MinIndex_i=Param.IndexRange.MinIndex_i(iview_image);
            % MaxIndex_j=1;%default
            % MinIndex_j=1;
            % if isfield(Param.IndexRange,'MaxIndex_j')&&isfield(Param.IndexRange,'MinIndex_j')...
            %         && numel(Param.IndexRange.MaxIndex_j')>=iview_image &&numel(Param.IndexRange.MinIndex_j')>=iview_image
            %     MaxIndex_j=Param.IndexRange.MaxIndex_j(iview_image);
            %     MinIndex_j=Param.IndexRange.MinIndex_j(iview_image);
            % end
            % %update the bounds if possible
            % if isfield(SeriesData,'i1_series')&&numel(SeriesData.i1_series)>=iview_image
            %     if size(SeriesData.i1_series{iview_image},2)==2 && min(min(SeriesData.i1_series{iview_image}(:,1,:)))==0
            %         MinIndex_j=1;% index j set to 1 by default
            %         MaxIndex_j=1;
            %         MinIndex_i=find(SeriesData.i1_series{iview_image}(1,2,:), 1 )-1;% min ref index i detected in the series (corresponding to the first non-zero value of i1_series, except for zero index)
            %         MaxIndex_i=find(SeriesData.i1_series{iview_image}(1,2,:),1,'last' )-1;%max ref index i detected in the series (corresponding to the last non-zero value of i1_series)
            %     else
            %         ref_i=squeeze(max(SeriesData.i1_series{iview_image}(1,:,:),[],2));% select ref_j index for each ref_i
            %         ref_j=squeeze(max(SeriesData.j1_series{iview_image}(1,:,:),[],3));% select ref_i index for each ref_j
            %         MinIndex_i=min(find(ref_i))-1;
            %         MaxIndex_i=max(find(ref_i))-1;
            %         MaxIndex_j=max(find(ref_j))-1;
            %         MinIndex_j=min(find(ref_j))-1;
            %     end
            % end
            % if ~isfield(Param.IndexRange,'first_j')||isequal(MaxIndex_j,MinIndex_j)% no possibility of j pairs
            %     set(handles.ListPairMode,'Value',1)
            %     set(handles.ListPairMode,'String',{'series(Di)'})
            % elseif  MaxIndex_i==1 && MaxIndex_j>1% simple series in j
            %     set(handles.ListPairMode,'String',{'pair j1-j2';'series(Dj)'})
            %     if  MaxIndex_j <= 10
            %         set(handles.ListPairMode,'Value',1)% advice 'pair j1-j2' except in MaxIndex_j is large
            %     end
            % else
            %     set(handles.ListPairMode,'String',{'pair j1-j2';'series(Dj)';'series(Di)'})%multiple choice
            %     if strcmp(NomTypeNc,'_1-2_1')
            %         set(handles.ListPairMode,'Value',3)% advise 'series(Di)'
            %     elseif  MaxIndex_j <= 10
            %         set(handles.ListPairMode,'Value',1)% advice 'pair j1-j2' except in MaxIndex_j is large
            %     end
            % end
            
            %%  transfer the time from the GUI series, or use file index by default
            % time=[];
            % TimeUnit='frame'; %default
            % CoordUnit='';%default
            % pxcm_search=1;
            % if isfield(SeriesData,'Time') && ~isempty(SeriesData.Time{1})
            %     time=SeriesData.Time{1};
            % end
            % if isfield(Param.IndexRange,'TimeUnit')&&~isempty(Param.IndexRange.TimeUnit)
            %     TimeUnit=Param.IndexRange.TimeUnit;
            % end
            % % if isfield(SeriesData,'TimeSource')
            % %     set(handles.TimeSource,'String',SeriesData.TimeSource)
            % % end
            % if isfield(SeriesData,'GeometryCalib')
            %     tsai=SeriesData.GeometryCalib;
            %     if isfield(tsai,'fx_fy')
            %         pxcm_search=max(tsai.fx_fy(1),tsai.fx_fy(2));%pixels:cm estimated for the search range
            %     end
            %     if isfield(tsai,'CoordUnit')
            %         CoordUnit=tsai.CoordUnit;
            %     end
            % end
            
            %% timing display
            %show the reference image edit box if relevant (not needed for movies or in the absence of time information
            % if numel(time)>=2 % if there are at least two time values to define dt
            %     if size(time,2)<MaxIndex_i
            %         msgbox_uvmat('WARNING','maximum i index restricted by the timing of the xml file');
            %     elseif size(time,1)<MaxIndex_j
            %         msgbox_uvmat('WARNING','maximum j index restricted by the timing of the xml file');
            %     end
            %     MaxIndex_i=min(size(time,2),MaxIndex_i);%possibly adjust the max index according to time data
            %     MaxIndex_j=min(size(time,1),MaxIndex_j);
            %     set(handles.TimeSource,'String',Param.IndexRange.TimeSource);
            % else
            %     set(handles.TimeSource,'String',''); %xml file not used for timing
            %     TimeUnit='frame';
            %     time=ones(MaxIndex_j-MinIndex_j+1,1)*(MinIndex_i:MaxIndex_i);
            %     time=time+0.001*(MinIndex_j:MaxIndex_j)'*ones(1,MaxIndex_i-MinIndex_i+1);
            % end
            % CivInputData.Time=time;
            % CivInputData.NomTypeIma=NomTypeImaA;
            % set(handles.civ_input,'UserData',CivInputData)
            % set(handles.dt_unit,'String',['dt in m' TimeUnit]);%display dt in unit 10-3 of the time (e.g ms)
            % set(handles.TimeUnit,'String',TimeUnit);
            
            % indicate the min and max indices i and j on the GUI
            % set(handles.MinIndex_i,'String',num2str(MinIndex_i))
            % set(handles.MaxIndex_i,'String',num2str(MaxIndex_i))
            % set(handles.MinIndex_j,'String',num2str(MinIndex_j))
            % set(handles.MaxIndex_j,'String',num2str(MaxIndex_j))
            
            
            %% set the stereo_input options, depending on the input file content if a nc file has been opened
            ListOptions={'CheckCiv1', 'CheckFix1' 'CheckPatch1', 'CheckCiv2', 'CheckFix2', 'CheckPatch2', 'CheckCiv3', 'CheckFix3', 'CheckPatch3'};
            checkbox=zeros(size(ListOptions));%default
            checkrefresh=0;
            if ind_opening==0  %case of image opening, start with Civ1
                for index=1:numel(ListOptions)
                    checkbox(index)=get(handles.(ListOptions{index}),'Value');
                end
                index_max=find(checkbox, 1, 'last' );
                if isempty(index_max),index_max=1;end
                for index=1:index_max
                    set(handles.(ListOptions{index}),'Value',1)% select all operations starting from CIV1
                end
            else  %case of netcdf file opening, start with the stage read in the file if the input file is being refreshed
                if isequal(get(hhseries.REFRESH,'BackgroundColor'),[1 1 0]) &&...
                        ~(isfield(Param,'ActionInput') && isfield(Param.ActionInput,'ConfigSource'))
            %         answer=msgbox_uvmat('INPUT_Y-N',['import the civ parameters from the netcdf file']);
            %         if strcmp(answer,'Yes')
                        for index = 1:min(ind_opening,5)
                            set(handles.(ListOptions{index}),'value',0)
                            fill_civ_input(app, Data,handles); %fill civ_input with the parameters retrieved from an input Civ file
                        end
                        set(handles.ConfigSource,'String',FileInfo.FileName);
                        set(handles.(ListOptions{min(ind_opening+1,6)}),'value',1)
                        for index = ind_opening+2:6
                            set(handles.(ListOptions{index}),'value',0)
                        end
                        checkrefresh=1;
            %         end
                end
                if ind_opening>=3
                    set(handles.CheckCiv3,'Visible','on')% make visible the switch 'iterate/repet' for Civ2.
                else
                    set(handles.CheckCiv3,'Visible','off')
                end
            end
            
            %% introduce the stored Civ parameters  if available (from previous input or ImportConfig in series)
            if ~checkrefresh && isfield(Param,'ActionInput')&& strcmp(Param.ActionInput.Program,Param.Action.ActionName)% the program fits with the stored data
                %fill_GUI(Param.ActionInput,hObject);%fill the GUI with the parameters retrieved from the input Param
                fill_app(Param.ActionInput,app)
            %     hcheckgrid=findobj(handles.civ_input,'Tag','CheckGrid');
            %     for ilist=1:numel(hcheckgrid)
            %         if get(hcheckgrid(ilist),'Value')% if a grid is used, do not show Dx and Dy for an automatic grid
            %             hparent=get(hcheckgrid(ilist),'parent');%handles of the parent panel
            %             hchildren=get(hparent,'children');
            %             handle_dx=findobj(hchildren,'tag','num_Dx');
            %             handle_dy=findobj(hchildren,'tag','num_Dy');
            %             handle_title_dx=findobj(hchildren,'tag','title_Dx');
            %             handle_title_dy=findobj(hchildren,'tag','title_Dy');
            %             set(handle_dx,'Visible','off');
            %             set(handle_dy,'Visible','off');
            %             set(handle_title_dy,'Visible','off');
            %             set(handle_title_dx,'Visible','off');
            %         end
            %     end
            end
            
            %% set the reference indices from the input file indices
            %ind_opening=9;
            % if ~(isfield(Param,'ActionInput') && isfield(Param.ActionInput,'ConfigSource'))
            update_CivOptions(app, handles,ind_opening+1)% fill the menu of possible pairs
            % % end
            
            %% list the possible index pairs, depending on the option set in ListPairMode
            % ListPairMode_Callback([], [], handles)
            % ListPairCiv1_Callback(hObject, eventdata, handles)
            
            %% set the GUI to modal: wait for OK to close
            set(handles.civ_input,'WindowStyle','modal')% Make the GUI modal
            
            % set(handles.Civ3,'Visible','on')
            % set(handles.Fix3,'Visible','on')
            % set(handles.Fix3,'Visible','on')
            
            
            drawnow
            uiwait(handles.civ_input);
        end

        % Value changed function: CheckCiv1
        function CheckCiv1_Callback(app, event)
        update_frame(app,1)
        end

        % Value changed function: CheckCiv2
        function CheckCiv2_Callback(app, event)
        update_frame(app,4)
        end

        % Value changed function: CheckCiv3
        function CheckCiv3_Callback(app, event)
        update_frame(app,7)
        end

        % Value changed function: CheckDeformation, CheckDeformation_1
        function CheckDeformation_Callback(app, event)
            % --- Executes on button press in CheckDeformation.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.ConfigSource,'String','NEW')
            set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Value changed function: CheckFix1
        function CheckFix1_Callback(app, event)
        update_frame(app,2)
        end

        % Value changed function: CheckFix2
        function CheckFix2_Callback(app, event)
        update_frame(app, 5)
        end

        % Value changed function: CheckFix3
        function CheckFix3_Callback(app, event)
        update_frame(app, 8)
        end

        % Value changed function: CheckGrid
        function CheckGrid_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in CheckGrid.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            value=get(hObject,'Value');
            hparent=get(hObject,'parent');%handles of the parent panel
            hchildren=get(hparent,'children');
            handle_txtbox=findobj(hchildren,'tag','Grid');% look for the grid name box in the same panel
            handle_dx=findobj(hchildren,'tag','num_Dx');
            handle_dy=findobj(hchildren,'tag','num_Dy');
            handle_title_dx=findobj(hchildren,'tag','title_Dx');
            handle_title_dy=findobj(hchildren,'tag','title_Dy');
            testgrid=0;
            filegrid='';
            if value
                    hseries=findobj(allchild(0),'Tag','series');
                hhseries=guidata(hseries);
                InputTable=get(hhseries.InputTable,'Data');
                 ind_A=1;% line index of the (first) image series
                if strcmp(InputTable{1,5},'.nc');
                    ind_A=2;
                end
                filebase=InputTable{ind_A,1};
                [nbslice, flag_grid]=get_grid(app, filebase,handles);% look for a grid with appropriate name
                if isequal(flag_grid,1)
                    filegrid=[num2str(nbslice) 'grid'];
                    testgrid=1;
                else % browse for a grid
                    filegrid=get(hObject,'UserData');%look for previous grid name stored as UserData
                    if exist(filegrid,'file')
                        filebase=filegrid;
                    end
                   filegrid = uigetfile_uvmat('pick a grid file .grid:',filebase,'.grid');
                    set(hObject,'UserData',filegrid);%store for future use
                    if ~isempty(filegrid)
                        testgrid=1;
                    end
                    set(hObject,'UserData',filegrid);%store for future use
                end
            end
            if testgrid
                set(handle_dx,'Visible','off');
                set(handle_dy,'Visible','off');
                set(handle_title_dy,'Visible','off');
                set(handle_title_dx,'Visible','off');
                set(handle_txtbox,'Visible','on')
                set(handle_txtbox,'String',filegrid)
            else
                set(hObject,'Value',0);
                set(handle_dx,'Visible','on');
                set(handle_dy,'Visible','on');
                set(handle_title_dy,'Visible','on');
                set(handle_title_dx,'Visible','on');
                set(handle_txtbox,'Visible','off')
            end
            
            %% if hObject is on the checkciv1 frame, duplicate action for checkciv2 frame
            PanelName=get(hparent,'tag');
            if strcmp(PanelName,'Civ1')
                hchildren=get(handles.Civ2,'children');
                handle_checkbox=findobj(hchildren,'tag','CheckGrid');
                handle_txtbox=findobj(hchildren,'tag','Grid');
                handle_dx=findobj(hchildren,'tag','num_Dx');
                handle_dy=findobj(hchildren,'tag','num_Dy');
                handle_title_dx=findobj(hchildren,'tag','title_Dx');
                handle_title_dy=findobj(hchildren,'tag','title_Dy');
                set(handle_checkbox,'UserData',filegrid);%store for future use
                if testgrid
                    set(handle_checkbox,'Value',1);
                    set(handle_dx,'Visible','off');
                    set(handle_dy,'Visible','off');
                    set(handle_title_dx,'Visible','off');
                    set(handle_title_dy,'Visible','off');
                    set(handle_txtbox,'Visible','on')
                    set(handle_txtbox,'String',filegrid)
                end
            end
            set(hObject,'BackgroundColor',[1 0 1])
            set(handles.configSource,'String','NEW')
            set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Value changed function: CheckBackground, CheckMask
        function CheckMask_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in CheckMask: common to all panels (civ1, Civ2..)
            
            % Create GUIDE-style callback args - Added by Migration Tool
           % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
           
            % hparent=get(hObject,'parent');
            % parent_tag=get(hparent,'Tag');
            % hchildren=get(hparent,'children');
            % handle_txtbox=findobj(hchildren,'tag','Mask');% look for the mask name box in the same panel
            
            CheckMask=false;
            if app.CheckMask.Value
                hseries=findobj(allchild(0),'Tag','series');
                hhseries=guidata(hseries);
                InputTable=get(hhseries.InputTable,'Data');
                ind_A=1;% line index of the (first) image series
                if strcmp(InputTable{1,5},'.nc')
                    ind_A=2;
                end
                [nbslice, flag_mask]=get_mask(app,InputTable{ind_A,1});% look for a mask with appropriate name
                if isequal(flag_mask,1)
                    filemask=[num2str(nbslice) 'mask'];
                    CheckMask=true;
                else % browse for a mask
                    filemask= uigetfile_uvmat('pick a mask image file:',InputTable{ind_A,1},'image');
                    app.CheckMask.UserData=filemask;%store for future use
                    if ~isempty(filemask)
                        CheckMask=true;
                    end
                end
            end
            if CheckMask
              app.Mask_A.Visible='on';
              app.Mask_B.Visible='on';
                app.Mask_A.Value=filemask;
                app.Mask_B.Value=filemask;
            else
                app.Mask_A.Visible='off';
                app.Mask_B.Visible='off';
            end
            app.CheckMask.Value=CheckMask;
           
            app.ConfigSource.Value='NEW';
            app.ConfigSource.BackgroundColor=[1 0 1];
        end

        % Value changed function: CheckPatch1
        function CheckPatch1_Callback(app, event)
        update_frame(app,3)
        end

        % Value changed function: CheckPatch2
        function CheckPatch2_Callback(app, event)
        update_frame(app,6)
        end

        % Value changed function: CheckPatch3
        function CheckPatch3_Callback(app, event)
        update_frame(app,9)
        end

        % Value changed function: CheckRescale
        function CheckRescale_Callback(app, event)

            status='off';
            if app.CheckRescale.Value% if chck box has been selected
                status='on';
            end
            app.num_Maxtanh.Visible=status;
            app.ConfigSource.Value='NEW';

            % set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Value changed function: CheckThreshold
        function CheckThreshold_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in CheckThreshold.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            huipanel=get(hObject,'parent');
            obj(1)=findobj(huipanel,'Tag','num_MinIma');
            obj(2)=findobj(huipanel,'Tag','num_MaxIma');
            obj(3)=findobj(huipanel,'Tag','title_Threshold');
            if get(hObject,'Value')
                set(obj,'Visible','on')
            else
                set(obj,'Visible','off')
            end
            set(handles.ConfigSource,'String','NEW')
            set(handles.OK,'BackgroundColor',[1 0 1])
        end

        % Button pushed function: ImportParam
        function ImportParam_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in ImportParam.
            %------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            hseries=findobj(allchild(0),'Tag','series');
            hhseries=guidata(hseries);
            InputTable=get(hhseries.InputTable,'Data');% read the input file(s) table in the GUI series
            oldfile=InputTable{1,1};
            if isempty(oldfile)
                % use a file name stored in prefdir
                dir_perso=prefdir;
                profil_perso=fullfile(dir_perso,'uvmat_perso.mat');
                if exist(profil_perso,'file')
                    h=load (profil_perso);
                    if isfield(h,'RootPath') && ischar(h.RootPath)
                        oldfile=h.RootPath;
                    end
                end
            end
            filexml=uigetfile_uvmat('pick a xml parameter file for civ',oldfile,'.xml');% get the xml file containing processing parameters
            %proceed only if a file has been introduced by the browser
            if ~isempty(filexml)
                Param=xml2struct(filexml);% read the input xml file as a Matlab structure
            
               % Param.Action.RUN=0; %desactivate the input RUN=1
                if ~isfield(Param,'InputTable')||~isfield(Param,'IndexRange')
                    msgbox_uvmat('ERROR','invalid config file: open a file in a folder ''/0_XML''')
                    return
                end
                check_input=0;
                if isfield(Param,'ActionInput')
                    if isfield(Param.ActionInput,'Program')&& strcmp(Param.ActionInput.Program,'stereo_civ')
                        fill_GUI(Param.ActionInput,handles.civ_input)% fill the elements of the GUI series with the input parameters
                        set(handles.ConfigSource,'String',filexml)
                        check_input=1;
                        update_CivOptions(app, handles,0)
                    end
                end
                if ~check_input
                    msgbox_uvmat('ERROR','invalid config file (not for civ_series')
                    return
                end
            end
        end

        % Menu selected function: MenuHelp
        function MenuHelp_Callback(app, event)
            % -----------------------------------------------------------------------
            % -----------------------------------------------------------------------
            % --- Open the help html file
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % -----------------------------------------------------------------------
            web('http://servforge.legi.grenoble-inp.fr/projects/soft-uvmat/wiki/UvmatHelp#Civ')
        end

        % Button pushed function: OK
        function OK_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in OK: processing on local computer
            %ActionInput=read_app(app);
       
            % % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % 
            % %------------------------------------------------------------------------
            % 
            % ActionInput=read_GUI(handles.civ_input);% read the infos on the GUI civ_input
            % 
            %% correct mask or grid name for Windows system (replace '\' by '/')
            % if isfield(ActionInput,'Civ1')
            %     if isfield(ActionInput.Civ1,'Mask')
            %         ActionInput.Civ1.Mask=regexprep(ActionInput.Civ1.Mask,'\','/');
            %     end
            %     if isfield(ActionInput.Civ1,'Grid')
            %         ActionInput.Civ1.Grid=regexprep(ActionInput.Civ1.Grid,'\','/');
            %     end
            % end
            % if isfield(ActionInput,'Civ2')
            %     if isfield(ActionInput.Civ2,'Mask')
            %         ActionInput.Civ2.Mask=regexprep(ActionInput.Civ2.Mask,'\','/');
            %     end
            %     if isfield(ActionInput.Civ2,'Grid')
            %         ActionInput.Civ2.Grid=regexprep(ActionInput.Civ2.Grid,'\','/');
            %     end
            % end
            % 
            % %% exit the GUI and close it
           % handles.output.ActionInput=ActionInput;
             guidata(hObject, handles);% Update handles structure
             uiresume(app.civ_input);
        end

        % Button pushed function: SetDefaultParam
        function SetDefaultParam_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in SetDefaultParam.
            %------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            Param.ConfigSource='\default';
            
            %% Civ1 parameters
            Param.Civ1.CorrBoxSize=[101 101];
            Param.Civ1.SearchBoxSize=[100 100];
            Param.Civ1.SearchBoxShift=[0 0];
            Param.Civ1.CorrSmooth=1;
            Param.Civ1.Dx=20;
            Param.Civ1.Dy=20;
            Param.Civ1.CheckGrid=0;
            Param.Civ1.CheckMask=0;
            Param.Civ1.CheckThreshold=0;
            Param.Civ1.TestCiv1=0;
            
            %% Fix1 parameters
            Param.Fix1.MinCorr=0.2000;
            
            %% Patch1 parameters
            Param.Patch1.FieldSmooth=1000;
            Param.Patch1.MaxDiff=3;
            Param.Patch1.SubDomainSize=1000;
            Param.Patch1.TestPatch1=0;
            
            %% Civ2 parameters
            Param.Civ2.CorrBoxSize=[101 101];
            Param.Civ2.SearchBoxSize=[10 10];
            Param.Civ2.CorrSmooth=1;
            Param.Civ2.Dx=20;
            Param.Civ2.Dy=20;
            Param.Civ2.CheckGrid=0;
            Param.Civ2.CheckMask=0;
            Param.Civ2.CheckThreshold=0;
            Param.Civ2.TestCiv2=0;
            
            %% Fix2 parameters
            Param.Fix2.MinCorr=0.2000;
            
            %% Patch2 parameters
            Param.Patch2.FieldSmooth=1000;
            Param.Patch2.MaxDiff=2;
            Param.Patch2.SubDomainSize=1000;
            
            fill_GUI(Param,handles.civ_input)% fill the elements of the GUI series with the input parameters
        end

        % Value changed function: TestCiv1
        function TestCiv1_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%   TEST functions
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %------------------------------------------------------------------------
            % --- Executes on button press in TestCiv1: prepare the image correlation function
            %     activated by mouse motion

if app.TestCiv1.Value
    app.TestCiv1.BackgroundColor=[1 1 0]% paint TestCiv1 button to yellow to confirm civ launch
    app.CheckFix1.Value=0;% desactivate next step
    update_frame(app,2)

    % get info from the GUI 'series'
    hseries=findobj(allchild(0),'Tag','series');
    Param=read_GUI(hseries);
    disp('TEST MODE NOT IMPLEMENTED')

%     par_civ1=app.Civ1;
%     RootPath_A=Param.InputTable{1,1};
%     SubDir_A=Param.InputTable{1,2};
%     RootFile_A=Param.InputTable{1,3};
%     NomType_A=Param.InputTable{1,4};
%     FileExt_A=Param.InputTable{1,5};
% 
%     ImageName_A=fullfile_indices(RootPath_A,SubDir_A,RootFile_A,FileExt_A,NomType_A,i1,[],j1);
%     ImageName_B=fullfile_uvmat(RootPath_A,SubDir_A,RootFile_A,FileExt_A,NomType_A,i2,[],j2);
%     par_civ1.ImageA = read_image(ImageName_A);
%     par_civ1.ImageB = read_image(ImageName_B);
%     par_civ1.CorrSmooth=0;% will give only the grid of data points expected for PIV, computations will be activated by the fct mouse_motion.m
%     [Data.Civ1_X,Data.Civ1_Y,Data.Civ1_U,Data.Civ1_V,Data.Civ1_C,Data.Civ1_FF, ~, errormsg]=civ(par_civ1);
%     if ~isempty(errormsg)
%         disp(errormsg)
%         return
%     end % rmq: error msg displayed in civ_series
% 
%     %% create image data ImageData for display
%     ImageData.ListVarName={'ny','nx','A'};
%     ImageData.VarDimName= {'ny','nx',{'ny','nx'}};
%     ImageData.VarAttribute{1}.Role='coord_y';
%     ImageData.VarAttribute{2}.Role='coord_x';
%     ImageData.VarAttribute{3}.Role='scalar';
%     ImageData.A=par_civ1.ImageA; % get the first image
%     if ndims(ImageData.A)==3 %case of color image
%         ImageData.VarDimName= {'ny','nx',{'ny','nx','rgb'}};
%     end
%     ImageData.ny=[size(ImageData.A,1) 1];
%     ImageData.nx=[1 size(ImageData.A,2)];
%     ImageData.CoordUnit='pixel';% used to set equal scaling for x and y in image dispa=ly
% 
%     %% create the figure view_field for image visualization
%     hview_field=view_field(ImageData); %view the image in the GUI view_field
%     set(0,'CurrentFigure',hview_field)
%     hhview_field=guihandles(hview_field);
%     set(hview_field,'CurrentAxes',hhview_field.PlotAxes)
%     ViewData=get(hview_field,'UserData');
%     ViewData.CivHandle=handles.civ_input;% indicate the handle of the civ GUI in view_field
%     ViewData.PlotAxes.X=Data.Civ1_X';
%     ViewData.PlotAxes.Y=Data.Civ1_Y';
%     ViewData.PlotAxes.B=par_civ1.ImageB;%store the second image in the UserData of the GUI view_field
%     set(hview_field,'UserData',ViewData)% store the info in the UserData of image view_field, to be used by mouse_motion.m
% 
%     %% look for a current figure for image correlation display
%     corrfig=findobj(allchild(0),'tag','corrfig');
%     if isempty(corrfig)
%         corrfig=figure;
%         set(corrfig,'tag','corrfig')
%         set(corrfig,'name','image correlation')
%         set(corrfig,'DeleteFcn',{@closeview_field})%
%         set(handles.TestCiv1,'BackgroundColor',[1 0 0])
%     else
%         set(handles.TestCiv1,'BackgroundColor',[1 0 0])% paint button to red
%         corrfig=findobj(allchild(0),'tag','corrfig');% look for a current figure for image correlation display
%         if ~isempty(corrfig)
%             delete(corrfig)
%         end
%         hview_field=findobj(allchild(0),'tag','view_field');% look for view_field
%         if ~isempty(hview_field)
%             delete(hview_field)
%         end
%     end
% else
%     hview_field=findobj(allchild(0),'Tag','view_field'); %view the image in the GUI view_field
%     if ~isempty(hview_field)
%         delete(hview_field)
%     end
% end
 else
        app.TestCiv1.BackgroundColor=[1 0 0];% paint button to red
end
        end

        % Value changed function: get_ref_fix1
        function get_ref_fix1_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            filebase=get(handles.RootPath,'String');
            [FileName, PathName, filterindex] = uigetfile( ...
                {'*.nc', ' (*.nc)';
                '*.nc',  'netcdf files '; ...
                '*.*', 'All Files (*.*)'}, ...
                'Pick a file',filebase);
            
            fileinput=[PathName FileName];
            sizf=size(fileinput);
            if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
            %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
            [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
            ref.filebase=fullfile(Path,File);
            % ref.num_a=stra2num(str_a);
            % ref.num_b=stra2num(str_b);
            % ref.num1=str2double(field_count);
            % ref.num2=str2double(str2);
            browse=[];%initialisation
            if ~isequal(ref.ext,'.nc')
                msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                return
            end
            set(handles.ref_fix1,'String',[fullfile(ref.subdir,File) '....nc']);
            set(handles.ref_fix1,'UserData',ref)
            menu_field{1}='civ1';
            Data=nc2struct(fileinput,[]);
            if isfield(Data,'patch') && isequal(Data.patch,1)
                menu_field{2}='filter1';
            end
            if isfield(Data,'civ2') && isequal(Data.civ2,1)
                menu_field{3}='civ2';
            end
            if isfield(Data,'patch2') && isequal(Data.patch2,1)
                menu_field{4}='filter2';
            end
            set(handles.field_ref1,'String',menu_field);
            set(handles.field_ref1,'Value',length(menu_field));
            set(handles.num_MinVel,'Value',2);
            set(handles.num_MinVel,'String','1');%default threshold
            set(handles.ref_fix1,'Enable','on')
        end

        % Value changed function: get_ref_fix2, get_ref_fix2_1
        function get_ref_fix2_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on button press in get_ref_fix2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %------------------------------------------------------------------------
            if isequal(get(handles.get_ref_fix2,'Value'),1)
                filebase=get(handles.RootPath,'String');
                [FileName, PathName, filterindex] = uigetfile( ...
                    {'*.nc', ' (*.nc)';
                    '*.nc',  'netcdf files '; ...
                    '*.*', 'All Files (*.*)'}, ...
                    'Pick a file',filebase);
                fileinput=[PathName FileName];
                sizf=size(fileinput);
                if (~ischar(fileinput)||~isequal(sizf(1),1)),return;end %stop if fileinput not a character string
                %[Path,File,field_count,str2,str_a,str_b,ref.ext,ref.nom_type,ref.subdir]=name2display(fileinput);
                [Path,ref.subdir,File,ref.num1,ref.num2,ref.num_a,ref.num_b,ref.ext,ref.nom_type]=fileparts_uvmat(fileinput);
                ref.filebase=fullfile(Path,File);
                %     ref.num_a=stra2num(str_a);
                %     ref.num_b=stra2num(str_b);
                %     ref.num1=str2num(field_count);
                %     ref.num2=str2num(str2);
                browse=[];%initialisation
                if ~isequal(ref.ext,'.nc')
                    msgbox_uvmat('ERROR','the reference file must be in netcdf format (*.nc)')
                    return
                end
                set(handles.ref_fix2,'String',[fullfile(ref.subdir,File) '....nc']);
                set(handles.ref_fix2,'UserData',ref)
                menu_field{1}='civ1';
                Data=nc2struct(fileinput,[]);
                if isfield(Data,'patch') && isequal(Data.patch,1)
                    menu_field{2}='filter1';
                end
                if isfield(Data,'civ2') && isequal(Data.civ2,1)
                    menu_field{3}='civ2';
                end
                if isfield(Data,'patch2') && isequal(Data.patch2,1)
                    menu_field{4}='filter2';
                end
                set(handles.field_ref2,'String',menu_field);
                set(handles.field_ref2,'Value',length(menu_field));
                set(handles.num_MinVel,'Value',2);
                set(handles.num_MinVel,'String','1');%default threshold
                set(handles.ref_fix2,'Enable','on')
                set(handles.ref_fix2,'Visible','on')
                set(handles.field_ref2,'Visible','on')
            else
                set(handles.ref_fix2,'Visible','off')
                set(handles.field_ref2,'Visible','off')
            end
        end

        % Value changed function: num_CorrSmooth, num_CorrSmooth_1, 
        % ...and 1 other component
        function num_CorrSmooth_Callback(app, event)
            %------------------------------------------------------------------------
            % --- Executes on selection in menu CorrSmooth.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.ConfigSource,'Value','NEW')
            set(handles.OK,'BackgroundColor',[1 0 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.civ_input = uifigure('Visible', 'off');
            colormap(app.civ_input, 'parula');
            app.civ_input.Name = 'civ_input';
            app.civ_input.Tag = 'civ_input';
            app.MenuOpen = uimenu(app.civ_input);
            app.MenuOpen.Text = 'Open';
            app.MenuOpen.Tag = 'MenuOpen';
            app.MenuBrowse = uimenu(app.MenuOpen);
            app.MenuBrowse.Text = 'Browse';
            app.MenuBrowse.Tag = 'MenuBrowse';
            app.MenuFile_1 = uimenu(app.MenuOpen);
            app.MenuFile_1.Separator = 'on';
            app.MenuFile_1.Tag = 'MenuFile_1';
            app.MenuFile_2 = uimenu(app.MenuOpen);
            app.MenuFile_2.Tag = 'MenuFile_2';
            app.MenuFile_3 = uimenu(app.MenuOpen);
            app.MenuFile_3.Tag = 'MenuFile_3';
            app.MenuFile_4 = uimenu(app.MenuOpen);
            app.MenuFile_4.Tag = 'MenuFile_4';
            app.MenuFile_5 = uimenu(app.MenuOpen);
            app.MenuFile_5.Tag = 'MenuFile_5';
            app.MenuHelpHead = uimenu(app.civ_input);
            app.MenuHelpHead.Text = 'Help';
            app.MenuHelpHead.Tag = 'MenuHelpHead';
            app.MenuHelp = uimenu(app.MenuHelpHead);
            app.MenuHelp.MenuSelectedFcn = createCallbackFcn(app, @MenuHelp_Callback, true);
            app.MenuHelp.Text = 'Help';
            app.MenuHelp.Tag = 'MenuHelp';
            app.Patch2 = uipanel(app.civ_input);
            app.Patch2.Visible = 'off';
            app.Patch2.Tag = 'Patch2';
            app.subdomain_text2 = uilabel(app.Patch2);
            app.subdomain_text2.Tag = 'subdomain_text2';
            app.subdomain_text2.Text = 'subdomain size';
            app.num_FieldSmooth = uieditfield(app.Patch2, 'text');
            app.num_FieldSmooth.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth.Value = '1000';
            app.num_MaxDiff = uieditfield(app.Patch2, 'text');
            app.num_MaxDiff.Tag = 'num_MaxDiff';
            app.num_MaxDiff.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff.Value = '1.5';
            app.num_SubDomainSize = uieditfield(app.Patch2, 'text');
            app.num_SubDomainSize.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize.Value = '1000';
            app.FieldSmooth_txt = uilabel(app.Patch2);
            app.FieldSmooth_txt.Tag = 'FieldSmooth_txt';
            app.FieldSmooth_txt.Text = 'FieldSmooth';
            app.title_MaxDiff = uilabel(app.Patch2);
            app.title_MaxDiff.Tag = 'title_MaxDiff';
            app.title_MaxDiff.Text = 'MaxDiff';
            app.Fix2 = uipanel(app.civ_input);
            app.Fix2.Visible = 'off';
            app.Fix2.Tag = 'Fix2';
            app.thresh_vel2_text = uilabel(app.Fix2);
            app.thresh_vel2_text.Tag = 'thresh_vel2_text';
            app.thresh_vel2_text.Text = '| vel | <';
            app.get_ref_fix2 = uicheckbox(app.Fix2);
            app.get_ref_fix2.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix2_Callback, true);
            app.get_ref_fix2.Tag = 'get_ref_fix2';
            app.get_ref_fix2.Visible = 'off';
            app.get_ref_fix2.Tooltip = '''get_ref_fix2'': compare the velocity field to a reference field';
            app.get_ref_fix2.Text = 'GET_REF';
            app.ref_fix2 = uieditfield(app.Fix2, 'text');
            app.ref_fix2.Tag = 'ref_fix2';
            app.ref_fix2.Visible = 'off';
            app.ref_fix2.Tooltip = '''ref_fix2'': display the reference file name for fix';
            app.num_MaxVel = uieditfield(app.Fix2, 'text');
            app.num_MaxVel.Tag = 'num_MaxVel';
            app.num_MaxVel.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than threshold';
            app.num_MinVel = uieditfield(app.Fix2, 'text');
            app.num_MinVel.Tag = 'num_MinVel';
            app.num_MinVel.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vec2C_text = uilabel(app.Fix2);
            app.thresh_vec2C_text.Tag = 'thresh_vec2C_text';
            app.thresh_vec2C_text.Text = 'vec_C <';
            app.num_MinCorr = uieditfield(app.Fix2, 'text');
            app.num_MinCorr.Tag = 'num_MinCorr';
            app.num_MinCorr.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr.Value = '0.2';
            app.title_MaxVel = uilabel(app.Fix2);
            app.title_MaxVel.Tag = 'title_MaxVel';
            app.title_MaxVel.Text = '| vel |>';
            app.field_ref2 = uidropdown(app.Fix2);
            app.field_ref2.Items = {};
            app.field_ref2.Tag = 'field_ref2';
            app.field_ref2.Visible = 'off';
            app.field_ref2.Tooltip = '''field_ref2'':field used for reference (civ1,filter1...)';
            app.field_ref2.Value = {};
            app.Civ2 = uipanel(app.civ_input);
            app.Civ2.Visible = 'off';
            app.Civ2.Tag = 'Civ2';
            app.parameters_text = uilabel(app.Civ2);
            app.parameters_text.Tag = 'parameters_text';
            app.parameters_text.Text = 'correlation';
            app.num_CorrBoxSize_1 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_1.Value = '101';
            app.num_CorrBoxSize_2 = uieditfield(app.Civ2, 'text');
            app.num_CorrBoxSize_2.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_2.Value = '101';
            app.num_CorrSmooth = uieditfield(app.Civ2, 'text');
            app.num_CorrSmooth.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth.Tag = 'num_CorrSmooth';
            app.num_CorrSmooth.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth.Value = '1';
            app.ibx_civ2_text = uilabel(app.Civ2);
            app.ibx_civ2_text.Tag = 'ibx_civ2_text';
            app.ibx_civ2_text.Text = 'CorrBox x, y';
            app.CheckDeformation = uicheckbox(app.Civ2);
            app.CheckDeformation.ValueChangedFcn = createCallbackFcn(app, @CheckDeformation_Callback, true);
            app.CheckDeformation.Tag = 'CheckDeformation';
            app.CheckDeformation.Tooltip = '''CheckDeformation'': introduces local image deformation by shear and rotation  in the correlation search';
            app.CheckDeformation.Text = 'deformation';
            app.CheckDeformation.Value = true;
            app.rho_civ2_title = uilabel(app.Civ2);
            app.rho_civ2_title.Tag = 'rho_civ2_title';
            app.rho_civ2_title.Tooltip = '''rho_civ2_title'': smoothing parameter for image correlations ';
            app.rho_civ2_title.Text = 'CorrSmooth';
            app.title_Dx = uilabel(app.Civ2);
            app.title_Dx.Tag = 'title_Dx';
            app.title_Dx.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx.Text = 'auto-grid Dx';
            app.title_Dy = uilabel(app.Civ2);
            app.title_Dy.Tag = 'title_Dy';
            app.title_Dy.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy.Text = 'Dy';
            app.num_Dx = uieditfield(app.Civ2, 'text');
            app.num_Dx.Tag = 'num_Dx';
            app.num_Dx.Tooltip = '''num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx.Value = '20';
            app.num_Dy = uieditfield(app.Civ2, 'text');
            app.num_Dy.Tag = 'num_Dy';
            app.num_Dy.Tooltip = 'num_Dy'': mesh along y (in pixel) for the grid of measureme';
            app.num_Dy.Value = '20';
            app.num_SearchRange_1 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_1.Tooltip = '''num_SearchRange_1'':earch  range(in pixels) along the x direction';
            app.num_SearchRange_1.Value = '10';
            app.num_SearchRange_2 = uieditfield(app.Civ2, 'text');
            app.num_SearchRange_2.Tag = 'num_SearchRange_2';
            app.num_SearchRange_2.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchRange_2.Value = '10';
            app.text94 = uilabel(app.Civ2);
            app.text94.Tag = 'text94';
            app.text94.Tooltip = '''is_title'':search range (in pixels) along each direction';
            app.text94.Text = 'Range x, y';
            app.CheckGrid_2 = uicheckbox(app.Civ2);
            app.CheckGrid_2.Tag = 'CheckGrid';
            app.CheckGrid_2.Tooltip = '''CheckGrid'': get custom grid file for civ1  (replaces Dx and Dy)';
            app.CheckGrid_2.Text = 'Grid';
            app.Grid_2 = uieditfield(app.Civ2, 'text');
            app.Grid_2.Tag = 'Grid';
            app.Grid_2.Enable = 'off';
            app.Grid_2.Visible = 'off';
            app.Grid_2.Tooltip = '''Grid'': custom grid file for civ2  (replaces dx and dy)''';
            app.Patch1 = uipanel(app.civ_input);
            app.Patch1.Visible = 'off';
            app.Patch1.Tag = 'Patch1';
            app.num_FieldSmooth_1 = uieditfield(app.Patch1, 'text');
            app.num_FieldSmooth_1.Tag = 'num_FieldSmooth';
            app.num_FieldSmooth_1.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_1.Value = '1000';
            app.num_MaxDiff_1 = uieditfield(app.Patch1, 'text');
            app.num_MaxDiff_1.Tag = 'num_MaxDiff';
            app.num_MaxDiff_1.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_1.Value = '3';
            app.num_SubDomainSize_1 = uieditfield(app.Patch1, 'text');
            app.num_SubDomainSize_1.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_1.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_1.Value = '1000';
            app.text72 = uilabel(app.Patch1);
            app.text72.Tag = 'text72';
            app.text72.Text = 'subdomain size';
            app.text75 = uilabel(app.Patch1);
            app.text75.Tag = 'text75';
            app.text75.Text = 'FieldSmooth';
            app.title_MaxDiff_1 = uilabel(app.Patch1);
            app.title_MaxDiff_1.Tag = 'title_MaxDiff_1';
            app.title_MaxDiff_1.Text = 'MaxDiff';
            app.Fix1 = uipanel(app.civ_input);
            app.Fix1.Visible = 'off';
            app.Fix1.Tag = 'Fix1';
            app.TitleMinVel = uilabel(app.Fix1);
            app.TitleMinVel.Tag = 'TitleMinVel';
            app.TitleMinVel.Text = {'| vel | <'; ''};
            app.get_ref_fix1 = uicheckbox(app.Fix1);
            app.get_ref_fix1.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix1_Callback, true);
            app.get_ref_fix1.Tag = 'get_ref_fix1';
            app.get_ref_fix1.Visible = 'off';
            app.get_ref_fix1.Tooltip = '''get_ref_fix1'': compares the velocity field to a reference';
            app.get_ref_fix1.Text = 'GET_REF';
            app.ref_fix1 = uieditfield(app.Fix1, 'text');
            app.ref_fix1.Tag = 'ref_fix1';
            app.ref_fix1.Visible = 'off';
            app.ref_fix1.Tooltip = '''ref_fix1'': display the reference file name for fix';
            app.num_MaxVel_1 = uieditfield(app.Fix1, 'text');
            app.num_MaxVel_1.Tag = 'num_MaxVel_1';
            app.num_MaxVel_1.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than this  threshold (in pixel displacement)';
            app.num_MinVel_1 = uieditfield(app.Fix1, 'text');
            app.num_MinVel_1.Tag = 'num_MinVel_1';
            app.num_MinVel_1.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.thresh_vecC_title = uilabel(app.Fix1);
            app.thresh_vecC_title.Tag = 'thresh_vecC_title';
            app.thresh_vecC_title.Tooltip = '''thresh_vecC_title'': removes vectors with image correlation value smaller than the threshold';
            app.thresh_vecC_title.Text = 'vec_C <';
            app.num_MinCorr_1 = uieditfield(app.Fix1, 'text');
            app.num_MinCorr_1.Tag = 'num_MinCorr_1';
            app.num_MinCorr_1.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr_1.Value = '0.2';
            app.title_MaxVel_1 = uilabel(app.Fix1);
            app.title_MaxVel_1.Tag = 'title_MaxVel_1';
            app.title_MaxVel_1.Text = '| vel | >';
            app.field_ref1 = uidropdown(app.Fix1);
            app.field_ref1.Items = {};
            app.field_ref1.Tag = 'field_ref1';
            app.field_ref1.Visible = 'off';
            app.field_ref1.Tooltip = '''field_ref1'':field used for reference (civ1,filter1...)';
            app.field_ref1.Value = {};
            app.Civ1 = uipanel(app.civ_input);
            app.Civ1.Visible = 'off';
            app.Civ1.Tag = 'Civ1';
            app.title_Dx_1 = uilabel(app.Civ1);
            app.title_Dx_1.Tag = 'title_Dx_1';
            app.title_Dx_1.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.title_Dx_1.Text = 'auto-grid Dx';
            app.title_Dy_1 = uilabel(app.Civ1);
            app.title_Dy_1.Tag = 'title_Dy_1';
            app.title_Dy_1.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.title_Dy_1.Text = 'Dy';
            app.num_CorrBoxSize_1_1 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_1_1.Tag = 'num_CorrBoxSize_1';
            app.num_CorrBoxSize_1_1.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_1_1.Value = '101';
            app.num_CorrBoxSize_2_1 = uieditfield(app.Civ1, 'text');
            app.num_CorrBoxSize_2_1.Tag = 'num_CorrBoxSize_2';
            app.num_CorrBoxSize_2_1.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_2_1.Value = '101';
            app.num_SearchRange_1_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_1_1.Tag = 'num_SearchRange_1';
            app.num_SearchRange_1_1.Tooltip = '''num_SearchRange_1'':search range (in pixels) along the x direction';
            app.num_SearchRange_1_1.Value = '100';
            app.num_SearchRange_2_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchRange_2_1.Tag = 'num_SearchRange_2';
            app.num_SearchRange_2_1.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchRange_2_1.Value = '100';
            app.num_SearchBoxShift_1 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_1.Tag = 'num_SearchBoxShift_1';
            app.num_SearchBoxShift_1.Tooltip = '''num_SearchBoxShift_1'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_1.Value = '0';
            app.num_SearchBoxShift_2 = uieditfield(app.Civ1, 'text');
            app.num_SearchBoxShift_2.Tag = 'num_SearchBoxShift_2';
            app.num_SearchBoxShift_2.Tooltip = '''num_SearchBoxShift_2'': systematic shift (in pixels) of the search box (take into account a mean flow)';
            app.num_SearchBoxShift_2.Value = '0';
            app.num_CorrSmooth_1 = uieditfield(app.Civ1, 'text');
            app.num_CorrSmooth_1.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_1.Tag = 'num_CorrSmooth';
            app.num_CorrSmooth_1.Tooltip = '''num_CorrSmooth_1'': smoothing parameter for image correlations ''';
            app.num_CorrSmooth_1.Value = '1';
            app.num_Dx_1 = uieditfield(app.Civ1, 'text');
            app.num_Dx_1.InputType = 'digits';
            app.num_Dx_1.Tag = 'num_Dx';
            app.num_Dx_1.Tooltip = '''Num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_1.Value = '20';
            app.num_Dy_1 = uieditfield(app.Civ1, 'text');
            app.num_Dy_1.InputType = 'digits';
            app.num_Dy_1.Tag = 'num_Dy';
            app.num_Dy_1.Tooltip = '''num_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.num_Dy_1.Value = '20';
            app.CheckGrid = uicheckbox(app.Civ1);
            app.CheckGrid.ValueChangedFcn = createCallbackFcn(app, @CheckGrid_Callback, true);
            app.CheckGrid.Tag = 'CheckGrid';
            app.CheckGrid.Tooltip = '''CheckGrid'': get custom grid file for civ1  (replaces Dx and Dy)';
            app.CheckGrid.Text = 'Grid';
            app.Grid = uieditfield(app.Civ1, 'text');
            app.Grid.Tag = 'Grid';
            app.Grid.Enable = 'off';
            app.Grid.Visible = 'off';
            app.Grid.Tooltip = '''Grid'': custom grid file for civ1  (replaces dx and dy)';
            app.parameters = uilabel(app.Civ1);
            app.parameters.Tag = 'parameters';
            app.parameters.Text = 'correlation';
            app.ib_title = uilabel(app.Civ1);
            app.ib_title.Tag = 'ib_title';
            app.ib_title.Tooltip = '''ib_title'':size of the correlation box (in pixels) along each direction';
            app.ib_title.Text = 'CorrBox x, y';
            app.is_title = uilabel(app.Civ1);
            app.is_title.Tag = 'is_title';
            app.is_title.Tooltip = '''is_title'':size of the search box (in pixels) along each direction';
            app.is_title.Text = 'Range x,y';
            app.shift_title = uilabel(app.Civ1);
            app.shift_title.Tag = 'shift_title';
            app.shift_title.Tooltip = '''shift_title'': systematic shift (in pixels) for search box (take into account a mean flow)';
            app.shift_title.Text = 'Shift x, y';
            app.rho_title = uilabel(app.Civ1);
            app.rho_title.Tag = 'rho_title';
            app.rho_title.Tooltip = '''rho_title'': smoothing parameter for image correlations ';
            app.rho_title.Text = 'CorrSmooth';
            app.TestCiv1 = uibutton(app.Civ1, 'state');
            app.TestCiv1.ValueChangedFcn = createCallbackFcn(app, @TestCiv1_Callback, true);
            app.TestCiv1.Tag = 'TestCiv1';
            app.TestCiv1.Tooltip = '''TestCiv1'' show the image correlation';
            app.TestCiv1.Text = 'TEST';
            app.CheckFix2 = uicheckbox(app.civ_input);
            app.CheckFix2.ValueChangedFcn = createCallbackFcn(app, @CheckFix2_Callback, true);
            app.CheckFix2.Tag = 'CheckFix2';
            app.CheckFix2.Tooltip = '''CheckFix2'': removes (flag)  false civ2 vectors with selected criteria';
            app.CheckFix2.Text = 'FIX2';
            app.OK = uibutton(app.civ_input, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Tag = 'OK';
            app.OK.Tooltip = '''OK'': send parameters to the GUI series';
            app.OK.Text = 'OK';
            app.Civ3 = uipanel(app.civ_input);
            app.Civ3.Visible = 'off';
            app.Civ3.Tag = 'Civ3';
            app.text103 = uilabel(app.Civ3);
            app.text103.Tag = 'text103';
            app.text103.Text = 'correlation';
            app.num_CorrBoxSize_1_2 = uieditfield(app.Civ3, 'text');
            app.num_CorrBoxSize_1_2.Tag = 'num_CorrBoxSize_1_2';
            app.num_CorrBoxSize_1_2.Tooltip = '''num_CorrBoxSize_1'':size of the correlation box (in pixels) along the x direction';
            app.num_CorrBoxSize_1_2.Value = '101';
            app.num_CorrBoxSize_2_2 = uieditfield(app.Civ3, 'text');
            app.num_CorrBoxSize_2_2.Tag = 'num_CorrBoxSize_2_2';
            app.num_CorrBoxSize_2_2.Tooltip = '''num_CorrBoxSize_2'':size of the correlation box (in pixels) along the y direction';
            app.num_CorrBoxSize_2_2.Value = '101';
            app.num_CorrSmooth_2 = uieditfield(app.Civ3, 'text');
            app.num_CorrSmooth_2.ValueChangedFcn = createCallbackFcn(app, @num_CorrSmooth_Callback, true);
            app.num_CorrSmooth_2.Tag = 'num_CorrSmooth_3';
            app.num_CorrSmooth_2.Tooltip = '''num_CorrSmooth'': smoothing parameter for image correlations ';
            app.num_CorrSmooth_2.Value = '1';
            app.text104 = uilabel(app.Civ3);
            app.text104.Tag = 'text104';
            app.text104.Text = 'CorrBox x, y';
            app.CheckDeformation_1 = uicheckbox(app.Civ3);
            app.CheckDeformation_1.ValueChangedFcn = createCallbackFcn(app, @CheckDeformation_Callback, true);
            app.CheckDeformation_1.Tag = 'CheckDeformation_1';
            app.CheckDeformation_1.Tooltip = '''CheckDeformation'': introduces local image deformation by shear and rotation  in the correlation search';
            app.CheckDeformation_1.Text = 'deformation';
            app.CheckDeformation_1.Value = true;
            app.text105 = uilabel(app.Civ3);
            app.text105.Tag = 'text105';
            app.text105.Tooltip = '''rho_civ2_title'': smoothing parameter for image correlations ';
            app.text105.Text = 'CorrSmooth';
            app.text106 = uilabel(app.Civ3);
            app.text106.Tag = 'text106';
            app.text106.Tooltip = '''title_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.text106.Text = 'auto-grid Dx';
            app.text107 = uilabel(app.Civ3);
            app.text107.Tag = 'text107';
            app.text107.Tooltip = '''title_Dy'': mesh along y (in pixel) for the grid of measurement points';
            app.text107.Text = 'Dy';
            app.num_Dx_2 = uieditfield(app.Civ3, 'text');
            app.num_Dx_2.Tag = 'num_Dx_2';
            app.num_Dx_2.Tooltip = '''num_Dx'': mesh along x (in pixel) for the grid of measurement points';
            app.num_Dx_2.Value = '20';
            app.num_Dy_2 = uieditfield(app.Civ3, 'text');
            app.num_Dy_2.Tag = 'num_Dy_2';
            app.num_Dy_2.Tooltip = 'num_Dy'': mesh along y (in pixel) for the grid of measureme';
            app.num_Dy_2.Value = '20';
            app.num_SearchRange_1_2 = uieditfield(app.Civ3, 'text');
            app.num_SearchRange_1_2.Tag = 'num_SearchRange_1_2';
            app.num_SearchRange_1_2.Tooltip = '''num_SearchRange_1'':search range (in pixels) along the x direction';
            app.num_SearchRange_1_2.Value = '5';
            app.num_SearchBoxSize_2 = uieditfield(app.Civ3, 'text');
            app.num_SearchBoxSize_2.Tag = 'num_SearchBoxSize_2';
            app.num_SearchBoxSize_2.Tooltip = '''num_SearchRange_2'': search range (in pixels) along the y direction';
            app.num_SearchBoxSize_2.Value = '5';
            app.text108 = uilabel(app.Civ3);
            app.text108.Tag = 'text108';
            app.text108.Tooltip = '''is_title'':search range around the previous estimate (in pixels) in each direction';
            app.text108.Text = 'Range x, y';
            app.CheckGrid_3 = uicheckbox(app.Civ3);
            app.CheckGrid_3.Tag = 'CheckGrid';
            app.CheckGrid_3.Tooltip = '''CheckGrid'': get custom grid file for civ1  (replaces Dx and Dy)';
            app.CheckGrid_3.Text = 'Grid';
            app.Grid_3 = uieditfield(app.Civ3, 'text');
            app.Grid_3.Tag = 'Grid';
            app.Grid_3.Enable = 'off';
            app.Grid_3.Visible = 'off';
            app.Grid_3.Tooltip = '''Grid'': custom grid file for civ3  (replaces dx and dy)''';
            app.Patch3 = uipanel(app.civ_input);
            app.Patch3.Visible = 'off';
            app.Patch3.Tag = 'Patch3';
            app.text112 = uilabel(app.Patch3);
            app.text112.Tag = 'text112';
            app.text112.Text = 'subdomain size';
            app.num_MaxDiff_2 = uieditfield(app.Patch3, 'text');
            app.num_MaxDiff_2.Tag = 'num_MaxDiff';
            app.num_MaxDiff_2.Tooltip = '''num_MaxDiff'': vectors are flaged as false if the difference with filtered value exceeds this value (in px)';
            app.num_MaxDiff_2.Value = '1.5';
            app.num_SubDomainSize_2 = uieditfield(app.Patch3, 'text');
            app.num_SubDomainSize_2.Tag = 'num_SubDomainSize';
            app.num_SubDomainSize_2.Tooltip = '''num_SubDomainSize'': size of the subdomains used for interpolation (nb of vectors)';
            app.num_SubDomainSize_2.Value = '1000';
            app.text116 = uilabel(app.Patch3);
            app.text116.Tag = 'text116';
            app.text116.Text = 'MaxDiff';
            app.num_FieldSmooth_2 = uieditfield(app.Patch3, 'text');
            app.num_FieldSmooth_2.Tag = 'num_FieldSmooth_2';
            app.num_FieldSmooth_2.Tooltip = '''num_FieldSmooth'': smoothing parameter (large values increase smoothing)';
            app.num_FieldSmooth_2.Value = '500';
            app.text115 = uilabel(app.Patch3);
            app.text115.Tag = 'text115';
            app.text115.Text = 'FieldSmooth';
            app.Fix3 = uipanel(app.civ_input);
            app.Fix3.Visible = 'off';
            app.Fix3.Tag = 'Fix3';
            app.text109 = uilabel(app.Fix3);
            app.text109.Tag = 'text109';
            app.text109.Text = '| vel | <';
            app.get_ref_fix2_1 = uicheckbox(app.Fix3);
            app.get_ref_fix2_1.ValueChangedFcn = createCallbackFcn(app, @get_ref_fix2_Callback, true);
            app.get_ref_fix2_1.Tag = 'get_ref_fix2_1';
            app.get_ref_fix2_1.Visible = 'off';
            app.get_ref_fix2_1.Tooltip = '''get_ref_fix2'': compare the velocity field to a reference field';
            app.get_ref_fix2_1.Text = 'GET_REF';
            app.ref_fix2_1 = uieditfield(app.Fix3, 'text');
            app.ref_fix2_1.Tag = 'ref_fix2_1';
            app.ref_fix2_1.Visible = 'off';
            app.ref_fix2_1.Tooltip = '''ref_fix2'': display the reference file name for fix';
            app.num_MaxVel_2 = uieditfield(app.Fix3, 'text');
            app.num_MaxVel_2.Tag = 'num_MaxVel_2';
            app.num_MaxVel_2.Tooltip = '''num_MaxVel'': remove vel vectors  larger  than threshold';
            app.num_MinVel_2 = uieditfield(app.Fix3, 'text');
            app.num_MinVel_2.Tag = 'num_MinVel_2';
            app.num_MinVel_2.Tooltip = '''num_MinVel'': removes vectors whose modulus is smaller than the threshold (in picxel displacement)';
            app.text111 = uilabel(app.Fix3);
            app.text111.Tag = 'text111';
            app.text111.Text = '| vel |>';
            app.field_ref2_1 = uidropdown(app.Fix3);
            app.field_ref2_1.Items = {};
            app.field_ref2_1.Tag = 'field_ref2_1';
            app.field_ref2_1.Visible = 'off';
            app.field_ref2_1.Tooltip = '''field_ref2'':field used for reference (civ1,filter1...)';
            app.field_ref2_1.Value = {};
            app.text110 = uilabel(app.Fix3);
            app.text110.Tag = 'text110';
            app.text110.Text = 'vec_C <';
            app.num_MinCorr_2 = uieditfield(app.Fix3, 'text');
            app.num_MinCorr_2.Tag = 'num_MinCorr_2';
            app.num_MinCorr_2.Tooltip = '''num_MinCorr'': removes vectors with image correlation value smaller than the threshold';
            app.num_MinCorr_2.Value = '0.2';
            app.text93 = uilabel(app.civ_input);
            app.text93.Tag = 'text93';
            app.text93.Text = 'source config:';
            app.ConfigSource = uieditfield(app.civ_input, 'text');
            app.ConfigSource.Tag = 'ConfigSource';
            app.ConfigSource.Enable = 'off';
            app.ConfigSource.Tooltip = '''ConfigSource'': source of the current parameters';
            app.ImportParam = uibutton(app.civ_input, 'push');
            app.ImportParam.ButtonPushedFcn = createCallbackFcn(app, @ImportParam_Callback, true);
            app.ImportParam.Tag = 'ImportParam';
            app.ImportParam.Tooltip = '''ImportParam'' browse   xml file (in a folder 0_XML) to import civ parameters ';
            app.ImportParam.Text = 'IMPORT';
            app.SetDefaultParam = uibutton(app.civ_input, 'push');
            app.SetDefaultParam.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultParam_Callback, true);
            app.SetDefaultParam.Tag = 'SetDefaultParam';
            app.SetDefaultParam.Tooltip = '''SetDefaultParam'': press to revert to default civ parameters';
            app.SetDefaultParam.Text = 'DEFAULT';
            app.CheckTest = uicheckbox(app.civ_input);
            app.CheckTest.Tag = 'CheckTest';
            app.CheckTest.Tooltip = '''CheckLSM'' save only physics data to recude output weight';
            app.CheckTest.Text = 'Test mode';
            app.num_resolution = uieditfield(app.civ_input, 'text');
            app.num_resolution.Tag = 'num_resolution';
            app.num_resolution.Tooltip = '''num_resolution'': factor for the size of the images projected in the phys coordinates, > 1 to increase the resolution';
            app.num_resolution.Value = '1';
            app.text117 = uilabel(app.civ_input);
            app.text117.Tag = 'text117';
            app.text117.Visible = 'off';
            app.text117.Text = 'resolution factor';
            app.CheckCiv3 = uicheckbox(app.civ_input);
            app.CheckCiv3.ValueChangedFcn = createCallbackFcn(app, @CheckCiv3_Callback, true);
            app.CheckCiv3.Tag = 'CheckCiv3';
            app.CheckCiv3.Tooltip = '''CheckCiv3'': select first PIV processing';
            app.CheckCiv3.Text = 'CIV3';
            app.text118 = uilabel(app.civ_input);
            app.text118.Tag = 'text118';
            app.text118.Visible = 'off';
            app.text118.Text = 'threshold';
            app.title_Threshold = uilabel(app.civ_input);
            app.title_Threshold.Tag = 'title_Threshold';
            app.title_Threshold.Visible = 'off';
            app.title_Threshold.Text = '< image <';
            app.CheckMask = uicheckbox(app.civ_input);
            app.CheckMask.ValueChangedFcn = createCallbackFcn(app, @CheckMask_Callback, true);
            app.CheckMask.Tag = 'CheckMask';
            app.CheckMask.Tooltip = '''CheckMask'': use mask file for civ, or creates it if it does not exist''';
            app.CheckMask.Text = 'Mask';
            app.Mask_A = uieditfield(app.civ_input, 'text');
            app.Mask_A.Tag = 'Mask_A';
            app.Mask_A.Visible = 'off';
            app.Mask_A.Tooltip = '''Mask_A'': display a selected mask series for images A''';
            app.CheckThreshold = uicheckbox(app.civ_input);
            app.CheckThreshold.ValueChangedFcn = createCallbackFcn(app, @CheckThreshold_Callback, true);
            app.CheckThreshold.Tag = 'CheckThreshold';
            app.CheckThreshold.Tooltip = '''CheckThreshold'': set thresholds on image intensity';
            app.CheckThreshold.Text = 'Threshold';
            app.num_MinIma = uieditfield(app.civ_input, 'text');
            app.num_MinIma.Tag = 'num_MinIma';
            app.num_MinIma.Visible = 'off';
            app.num_MinIma.Tooltip = '''num_MinIma'': lower luminosity threshold for PIV processing';
            app.num_MinIma.Value = '0';
            app.num_MaxIma = uieditfield(app.civ_input, 'text');
            app.num_MaxIma.Tag = 'num_MaxIma';
            app.num_MaxIma.Visible = 'off';
            app.num_MaxIma.Tooltip = '''num_MaxIma'': higher luminosity threshold for PIV processing';
            app.num_NbSlice = uieditfield(app.civ_input, 'text');
            app.num_NbSlice.Tag = 'num_NbSlice';
            app.num_NbSlice.Visible = 'off';
            app.num_NbSlice.Tooltip = '''num_NbSlice'': number of slices for the mask';
            app.CheckBackground = uicheckbox(app.civ_input);
            app.CheckBackground.ValueChangedFcn = createCallbackFcn(app, @CheckMask_Callback, true);
            app.CheckBackground.Tag = 'CheckBackground';
            app.CheckBackground.Tooltip = '''CheckMask'': use mask file for civ1, or creates it if it does not exist';
            app.CheckBackground.Text = 'Background';
            app.Background = uieditfield(app.civ_input, 'text');
            app.Background.Tag = 'Background_A';
            app.Background.Visible = 'off';
            app.Background.Tooltip = '''Background'': display background image filename';
            app.num_BkgndPeriod = uieditfield(app.civ_input, 'text');
            app.num_BkgndPeriod.Tag = 'num_BkgndPeriod_A';
            app.num_BkgndPeriod.Visible = 'off';
            app.num_BkgndPeriod.Tooltip = '''num_BkgndPeriod'': index i period for background determination';
            app.CheckRescale = uicheckbox(app.civ_input);
            app.CheckRescale.ValueChangedFcn = createCallbackFcn(app, @CheckRescale_Callback, true);
            app.CheckRescale.Tag = 'CheckRescale';
            app.CheckRescale.Tooltip = '''CheckRescale'': rescale the image luminosity I by threshold*tanh(I/threshold)';
            app.CheckRescale.Text = 'Rescale tanh';
            app.num_Maxtanh = uieditfield(app.civ_input, 'text');
            app.num_Maxtanh.Tag = 'num_Maxtanh';
            app.num_Maxtanh.Visible = 'off';
            app.num_Maxtanh.Tooltip = '''num_Maxtanh'': threshold for the tanh image intensity rescaling';
            app.CheckFix1 = uicheckbox(app.civ_input);
            app.CheckFix1.ValueChangedFcn = createCallbackFcn(app, @CheckFix1_Callback, true);
            app.CheckFix1.Tag = 'CheckFix1';
            app.CheckFix1.Tooltip = '''CheckFix1'': flags false vectors with selected criteria';
            app.CheckFix1.Text = 'FIX1';
            app.CheckPatch2 = uicheckbox(app.civ_input);
            app.CheckPatch2.ValueChangedFcn = createCallbackFcn(app, @CheckPatch2_Callback, true);
            app.CheckPatch2.Tag = 'CheckPatch2';
            app.CheckPatch2.Tooltip = '''CheckPatch2'': interpolates and smooths the civ2 velocity field';
            app.CheckPatch2.Text = 'PATCH2';
            app.CheckFix3 = uicheckbox(app.civ_input);
            app.CheckFix3.ValueChangedFcn = createCallbackFcn(app, @CheckFix3_Callback, true);
            app.CheckFix3.Tag = 'CheckFix3';
            app.CheckFix3.Tooltip = '''CheckFix3'': flags false vectors with selected criteria';
            app.CheckFix3.Text = 'FIX3';
            app.CheckPatch3 = uicheckbox(app.civ_input);
            app.CheckPatch3.ValueChangedFcn = createCallbackFcn(app, @CheckPatch3_Callback, true);
            app.CheckPatch3.Tag = 'CheckPatch3';
            app.CheckPatch3.Tooltip = '''CheckPatch3'': interpolates and smooths the velocity field';
            app.CheckPatch3.Text = 'PATCH3';
            app.CheckCiv1 = uicheckbox(app.civ_input);
            app.CheckCiv1.ValueChangedFcn = createCallbackFcn(app, @CheckCiv1_Callback, true);
            app.CheckCiv1.Tag = 'CheckCiv1';
            app.CheckCiv1.Tooltip = '''CheckCiv1'': select first PIV processing';
            app.CheckCiv1.Text = 'CIV1';
            app.CheckCiv2 = uicheckbox(app.civ_input);
            app.CheckCiv2.ValueChangedFcn = createCallbackFcn(app, @CheckCiv2_Callback, true);
            app.CheckCiv2.Tag = 'CheckCiv2';
            app.CheckCiv2.Tooltip = '''CheckCiv2''; iterative PIV processing using the prior estimate from civ1 and patch1';
            app.CheckCiv2.Text = 'CIV2';
            app.CheckPatch1 = uicheckbox(app.civ_input);
            app.CheckPatch1.ValueChangedFcn = createCallbackFcn(app, @CheckPatch1_Callback, true);
            app.CheckPatch1.Tag = 'CheckPatch1';
            app.CheckPatch1.Tooltip = '''CheckPatch1'': interpolates and smooths the velocity field';
            app.CheckPatch1.Text = 'PATCH1';
            app.Mask_B = uieditfield(app.civ_input, 'text');
            app.Mask_B.Tag = 'Mask_B';
            app.Mask_B.Visible = 'off';
            app.Mask_B.Tooltip = '''Mask_B: display a selected mask series for images B''';
            app.Background_B = uieditfield(app.civ_input, 'text');
            app.Background_B.Tag = 'Background_B';
            app.Background_B.Visible = 'off';
            app.Background_B.Tooltip = '''Background'': display background image filename';
            app.num_BkgndPeriod_B = uieditfield(app.civ_input, 'text');
            app.num_BkgndPeriod_B.Tag = 'num_BkgndPeriod_B';
            app.num_BkgndPeriod_B.Visible = 'off';
            app.num_BkgndPeriod_B.Tooltip = '''num_BkgndPeriod'': index i period for background determination';
            % Show the figure after all components are created
            app.civ_input.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stereo_input(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.civ_input)

                % Execute the startup function
                runStartupFcn(app, @(app)stereo_input_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.civ_input)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.civ_input)
        end
    end
end